classdef SuperSpectrogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SuperSpectrogramUIFigure       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DisplayfromFileTab             matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        ComputeParametersButton        matlab.ui.control.Button
        UpdateDiagramButton            matlab.ui.control.Button
        SelectFileButton               matlab.ui.control.Button
        FileLocationLabel              matlab.ui.control.Label
        StatusEditFieldLabel           matlab.ui.control.Label
        StatusEditField                matlab.ui.control.EditField
        SpectreThresholddBEditField_2Label  matlab.ui.control.Label
        SpectreThresholddBEditField_2  matlab.ui.control.NumericEditField
        LowestNoteDropDown_2Label      matlab.ui.control.Label
        LowestNoteDropDown_2           matlab.ui.control.DropDown
        HighestNoteDropDown_2Label     matlab.ui.control.Label
        HighestNoteDropDown_2          matlab.ui.control.DropDown
        NotesSequenceDropDown_2Label   matlab.ui.control.Label
        NotesSequenceDropDown_2        matlab.ui.control.DropDown
        PlaySelectedAudioButton        matlab.ui.control.Button
        TargetRateHzDropDownLabel      matlab.ui.control.Label
        TargetRateHzDropDown           matlab.ui.control.DropDown
        UIAxes_2                       matlab.ui.control.UIAxes
        MinTimeSliderLabel             matlab.ui.control.Label
        MinTimeSlider                  matlab.ui.control.Slider
        MaxTimeSliderLabel             matlab.ui.control.Label
        MaxTimeSlider                  matlab.ui.control.Slider
        OpenDiagraminaNewWindowButton  matlab.ui.control.Button
        RecordandStreamTab             matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        StartRecordingButton           matlab.ui.control.Button
        SamplingRateHzDropDownLabel    matlab.ui.control.Label
        SamplingRateHzDropDown         matlab.ui.control.DropDown
        RecordingTimesDropDownLabel    matlab.ui.control.Label
        RecordingTimesDropDown         matlab.ui.control.DropDown
        LowestNoteDropDownLabel        matlab.ui.control.Label
        LowestNoteDropDown             matlab.ui.control.DropDown
        HighestNoteDropDownLabel       matlab.ui.control.Label
        HighestNoteDropDown            matlab.ui.control.DropDown
        RefreshRatesDropDownLabel      matlab.ui.control.Label
        RefreshRatesDropDown           matlab.ui.control.DropDown
        RecalculateSpectrumButton      matlab.ui.control.Button
        SpectreThresholddBEditFieldLabel  matlab.ui.control.Label
        SpectreThresholddBEditField    matlab.ui.control.NumericEditField
        NotesSequenceDropDownLabel     matlab.ui.control.Label
        NotesSequenceDropDown          matlab.ui.control.DropDown
        RefreshDiagramButton           matlab.ui.control.Button
        PlaySelectedAudioButton_2      matlab.ui.control.Button
        StopRecordingButton            matlab.ui.control.Button
        RecFreqDisplay                 matlab.ui.control.Label
        ExportRecordingasWavButton     matlab.ui.control.Button
        UIAxes_3                       matlab.ui.control.UIAxes
        MinTimeSlider_2Label           matlab.ui.control.Label
        MinTimeSlider_2                matlab.ui.control.Slider
        MaxTimeSlider_2Label           matlab.ui.control.Label
        MaxTimeSlider_2                matlab.ui.control.Slider
        OpenDiagraminaNewWindowButton_2  matlab.ui.control.Button
        ConfigsAboutTab                matlab.ui.container.Tab
        MicrophoneDropDownLabel        matlab.ui.control.Label
        MicrophoneDropDown             matlab.ui.control.DropDown
        SpeakerDropDownLabel           matlab.ui.control.Label
        SpeakerDropDown                matlab.ui.control.DropDown
        AboutTextAreaLabel             matlab.ui.control.Label
        AboutTextArea                  matlab.ui.control.TextArea
        LicenseTextAreaLabel           matlab.ui.control.Label
        LicenseTextArea                matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        A; % Auxiliary array for defining STFT lengths
        Lold; % Store the length of the input signal resampled before filling with zeros
        Gv; % Matrix used to store the STFT results
        Fs; % Frequency of the input signal after resampling
        filename; % Location of the input file
        fs; % Frequency of the input signal before resampling
        P; % Summation of the channels of the input signal, if stereo
        ry; % Recorded signal
        recF; % Recorded signal sampling frequency
        procF = 0; % Sampling frequency used during the processing
        CpFlag = 0; % A flag for telling the program that the STFT was computed at least once
        RpFlag = 0; % The same as above, but for the recorded signal
        oldL; % Similar to Lold, but for the recorded signal
        Lthr; % Lower bound of the STFT threshold post-processing
        recLthr % Same as above, but for the recorded signal processing
        notes = ["sil";...
                                                   "C0";"C#0/Db0";"D0";"D#0/Eb0";"E0";"F0"; "F#0/Gb0";...
                     "G0"; "G#0/Ab0"; "A0"; "A#0/Bb0";"B0";"C1";"C#1/Db1";"D1";"D#1/Eb1";"E1";"F1"; "F#1/Gb1";...
                     "G1"; "G#1/Ab1"; "A1"; "A#1/Bb1";"B1";"C2";"C#2/Db2";"D2";"D#2/Eb2";"E2";"F2"; "F#2/Gb2";...
                     "G2"; "G#2/Ab2"; "A2"; "A#2/Bb2";"B2";"C3";"C#3/Db3";"D3";"D#3/Eb3";"E3";"F3"; "F#3/Gb3";...
                     "G3"; "G#3/Ab3"; "A3"; "A#3/Bb3";"B3";"C4";"C#4/Db4";"D4";"D#4/Eb4";"E4";"F4"; "F#4/Gb4";...
                     "G4"; "G#4/Ab4"; "A4"; "A#4/Bb4";"B4";"C5";"C#5/Db5";"D5";"D#5/Eb5";"E5";"F5"; "F#5/Gb5";...
                     "G5"; "G#5/Ab5"; "A5"; "A#5/Bb5";"B5";"C6";"C#6/Db6";"D6";"D#6/Eb6";"E6";"F6"; "F#6/Gb6"; ...
                     "G6"; "G#6/Ab6"; "A6"; "A#6/Bb6";"B6";"C7";"C#7/Db7";"D7";"D#7/Eb7";"E7";"F7"; "F#7/Gb7";...
                     "G7"; "G#7/Ab7"; "A7"; "A#7/Bb7";"B7";"C8";"C#8/Db8";"D8";"D#8/Eb8";"E8";"F8"; "F#8/Gb8";...
                     "G8"; "G#8/Ab8"; "A8"; "A#8/Bb8";"B8"]; % Define the names of the musical notes
                 %1       1        1           1        1        2        2          2 
        fnotes = [0;...
                                                                16.35  ; 17.32   ; 18.35   ; 19.45  ; 20.60  ; 21.83   ; 23.12   ; ...
                24.50  ; 25.96   ; 27.50  ; 29.14   ; 30.87   ; 32.7   ; 34.65   ; 36.71   ; 38.89  ; 41.20  ; 43.65   ; 46.25   ; ...
                49     ; 51.91   ; 55     ; 58.27   ; 61.74   ; 65.41  ; 69.3    ; 73.42   ; 77.78  ; 82.41  ; 87.31   ; 92.5    ; ... 
                98     ; 103.83  ; 110    ; 116.54  ; 123.47  ; 130.81 ; 138.59  ; 146.83  ; 155.56 ; 164.81 ; 174.61  ; 185     ; ...
                196.00 ; 207.65  ; 220.00 ; 233.08  ; 246.94  ; 261.63 ; 277.18  ; 293.66  ; 311.13 ; 329.63 ; 349.23  ; 369.99  ; ...
                392.00 ; 415.30  ; 440.00 ; 466.16  ; 493.88  ; 523.25 ; 554.37  ;  587.33 ; 622.25 ; 659.25 ; 698.46  ; 739.99  ; ...
                783.99 ; 830.61  ; 880.00 ; 932.33  ; 987.77  ; 1046.50; 1108.73 ; 1174.66 ; 1244.51; 1318.51; 1396.91 ; 1479.98 ; ...
                1567.98 ; 1661.22; 1760.00; 1864.66 ; 1975.53; 2093.00 ; 2217.46 ; 2349.32; 2489.02 ; 2637.02 ; 2793.83; 2959.96 ;...
                3135.96 ; 3322.44; 3520.00; 3729.31 ; 3951.07; 4186.01 ; 4434.92 ; 4698.63; 4978.03 ; 5274.04 ; 5587.65; 5919.91 ;...
                6271.93 ; 6644.88; 7040.00; 7458.62; 7902.13];
            %     G        Ab        A        Bb        B        C          C#       D         Eb       E         F         F#
        lastLnote = "sil"; % Just used to save the last Lower Note used during the display
        lastHnote = "C7"; % Just used to save the last Higher Note used during the display
        lastLnote2 = "sil";  % Same as above, but for the other mode
        lastHnote2 = "C7";
        stopRec = 0; % A flag used for breaking the recording loop
        indMin; % Those are indexes related to the slider selection of part of the signal
        indMax;
        iMin;
        iMax;
        recindMin; 
        recindMax;
        reciMin;
        reciMax;
        minNote; % Related to the lower note in the display
        maxNote; % Related to the higher note in the display
        recminNote; % Same as above, but for the recording case
        recmaxNote;
        Dir; % Direction of the notes in the spectrogram (up or down)
        recDir; % Same, but for the recording case
        maxProcTime = 10; % Maximum Processing Time, increasing it may use more memory than your computer have
        sliderWindow; % time length in seconds of the slider window
        recsliderWindow; % Same, for the recording case
        deviceReader; % Used to store the audio device reader data
        deviceWriter; % Used to store the audio device writer data
        ExternalUIFigure; % Used to Create Separate window UI Figure
        UIAxes3; % New Axes Created by Code
    end
    
    methods (Access = private)
        
        function TF = STFT(app, sig,sigma,gwin)
            wd = 16*sigma; %Define the window size based on the value of the sigma
            wdd2 = wd/2;    %Fixed, necessary for removing the edge effects
            wdd8 = wd/8;    %Fixed, don't change
            n = wd/32;      %Length of the window necessary for remove the edge effects (function of Variance)
            ns = ceil(length(sig)/wd); %Number of windows necessary to process all the signal length
            
            for k = 1:ns %For each window, compute a new STFT
               wSig(1:wd,1) = sig(1,(k-1)*wd+1:(k-1)*wd + wd); %wSig is the windowed signal
               TMP = stftcp(app,wSig,gwin);   
               TF(:,(k-1)*wdd8+1:k*wdd8) = TMP(:,1:8:wd);
               if k > 1 
                    wSig(1:wd,1) = sig(1,(k-2)*wd + 1 + wdd2:(k-1)*wd+ wdd2);
                    TMP = stftcp(app,wSig,gwin);   
                    TF(:,(k-1)*wdd8-n:(k-1)*wdd8+n) = TMP(:,8*(wdd8/2-n):8:8*(wdd8/2+n)); 
               end
            end
        end
        
        function Gv = recSTFT(app,sig,sigma,Lthr,gwin)
            wd = 16*sigma; %Define the window size based on the value of the sigma
            wdd8 = wd/8;    %Fixed, don't change
            ns = ceil(length(sig)/wd); %Number of windows necessary to process all the signal length
            
            for k = 1:ns %For each window, compute a new STFT
               wSig(1:wd,1) = sig(1,(k-1)*wd+1:(k-1)*wd + wd); %wSig is the windowed signal
               TMP = stftcp(app,wSig,gwin);   
               TF(:,(k-1)*wdd8+1:k*wdd8) = TMP(:,1:8:wd);
            end
            %% Representing the STFT graphically
            
            Fv = 20*log10(fftshift(abs(TF),1)); %Convert the amplitude of the TF matrix to dB
            Uthr = 0;  %Define the upper bound for the threshold (dB)
            Fv(Fv < Lthr | Fv > Uthr) = -Inf; % Set the undesired indexes to -Inf
            Gv = Fv(size(Fv,1)/2 + 1:size(Fv,1),1:size(Fv,2));
        end
        
        function TF = stftcp(~,f,gwin)
            cntr = length(f)/2;
            fsz = length(f);
            X = zeros(1,fsz);
            TF = zeros(fsz,fsz);
            
            x = zeros(1,2*fsz); % zero padding
            x(cntr+1:cntr+fsz) = f;
            
            for j = 1:fsz
               for k = 1:fsz
                  X(1,k) = gwin(k)*x(j+k);  
               end             
               TF(:,j) = fft(X).';
            end
        end
        
        function gwin = GenGaussianWindow(~, sigma)
            fsz = 16*sigma;
            gwin = exp(-((1:fsz)-fsz/2).^2/(2*sigma^2))/(sqrt(2*pi)*sigma);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
               disableDefaultInteractivity(app.UIAxes_2); % Don't allow the user to pan or zoom the graphic
               app.UIAxes_2.Toolbar.Visible = 'off'; % Hides the small toolbar at the corner
               hold(app.UIAxes_2,'on');
               disableDefaultInteractivity(app.UIAxes_3);
               app.UIAxes_3.Toolbar.Visible = 'off';
               hold(app.UIAxes_3, 'on');
               disableDefaultInteractivity(app.UIAxes);
               app.UIAxes.Toolbar.Visible = 'off';
               disableDefaultInteractivity(app.UIAxes2);
               app.UIAxes2.Toolbar.Visible = 'off';
               
               app.deviceWriter = audioDeviceWriter();
               app.deviceWriter.SupportVariableSizeInput = true;
               
               speakers = getAudioDevices(app.deviceWriter);
               app.deviceReader = audioDeviceReader();
               mics = getAudioDevices(app.deviceReader);
               app.MicrophoneDropDown.Items = mics;
               app.MicrophoneDropDown.Value = mics(1);
               app.SpeakerDropDown.Items = speakers;
               app.SpeakerDropDown.Value = speakers(1);
        end

        % Button pushed function: ComputeParametersButton
        function ComputeParametersButtonPushed(app, event)
            app.StatusEditField.Value = 'Computing'; % Update the status
            app.ComputeParametersButton.Enable = 'off'; % Disable the button
            app.ComputeParametersButton.Text = 'Computing'; % Change the button text
            
            app.PlaySelectedAudioButton.Enable = 'off';
            app.SelectFileButton.Enable = 'off';
            app.LowestNoteDropDown_2.Enable = 'off';
            app.HighestNoteDropDown_2.Enable = 'off';
            app.MinTimeSlider.Enable = 'off';
            app.MaxTimeSlider.Enable = 'off';
            app.StartRecordingButton.Enable = 'off';
            app.NotesSequenceDropDown_2.Enable = 'off';
            app.SpectreThresholddBEditField_2.Enable = 'off';
            app.TargetRateHzDropDown.Enable = 'off';
            
            pause(0.001); % Necessary for displaying the changes above
            
            cla(app.UIAxes); % Clear the diagram data
          
            TargetRate = str2double(app.TargetRateHzDropDown.Value); % Get the dropdown value and convert it to double
            
            switch TargetRate % Define l according to the Resampling Frequency
                case 8820
                    l = 8; % Define the level of detail of the STFT         
                case 4410
                    l = 7;
                case 2205
                    l = 6;
                case 1102.5
                    l = 5;
                otherwise
                    l = 7;
            end
            
            if TargetRate ~= app.fs % If the desired frequency is different from the input frequency...
                [p,q] = rat(TargetRate/app.fs); % Compute the resampling ratio
                y = resample(app.P,p,q);  % Resample the signal
            else
                y = app.P; % Otherwise, just use the input signal
            end
            
            app.Fs = TargetRate; %New sampling frequency            
            app.iMin = floor(app.MinTimeSlider.Value*app.Fs); % Minimum index of the signal, based on the slider selection
            app.iMax = floor(app.MaxTimeSlider.Value*app.Fs); % Maximum index of the signal, based on the slider selection
            
            y = y(app.iMin+1:app.iMax); % Extract the signal part required for processing
            
            if floor(app.sliderWindow*app.Fs) == length(y) % Required for solving a bug when the smallest signal window is used
                y(end+1) = 0; % Append one zero to the end of the signal
            end
            
            %% STFT Computation
                
            sigma =2^l; % Compute sigma
            gwin = GenGaussianWindow(app, sigma);
            app.Lthr = app.SpectreThresholddBEditField_2.Value; % Get the lower threshold based on user's input
            app.Lold = length(y); % Compute the current length of y
            
            st = 0; %stop flag
            Lr = app.Lold; % Initial value of Lr
            app.A = 0; % Initialize the array A
            while st ~= 1 % While the condition is not reached
               app.A = [app.A, 2^(nextpow2(Lr)-1)]; % Append to A a window size
               Lr = Lr - 2^(nextpow2(Lr)-1); % Update Lr
               if Lr <= 2^(l+4) % If the Window length is minimal
                   st = 1; % Stop the loop
                   app.A = [app.A,2^(l+4)]; % Append to A
               end
            end
            %Idea: Create a sliding window to compensate for the window border effect
            y = [y; zeros(sum(app.A)-app.Lold,1)]; % Complete y with zeros
            app.Gv = zeros(16*sigma,sum(app.A)/8); % Initialize Gv
            for i = 2:length(app.A) % Compute the STFT per signal segments
                sig = y(sum(app.A(1:i-1)) + 1 : sum(app.A(1:i)));
                app.Gv(:,sum(app.A(1:i-1))/8 + 1:sum(app.A(1:i))/8) = app.STFT(sig',sigma,gwin); % Compute the STFT
            end
            
            for i = 2:length(app.A)-1 % Correct the STFT in the edges
                sig = y(sum(app.A(1:i)) - 2^(l+3)+1 : sum(app.A(1:i)) + 2^(l+3));
                Gi = app.STFT(sig',sigma,gwin);
                app.Gv(:,sum(app.A(1:i))/8 - 2^(l-1)+1:sum(app.A(1:i))/8 + 2^(l-1)) = Gi(:,2^(l-1)+1:2^l + 2^(l-1)); % Compute the STFT
            end
            
            Uthr = 0;
            app.Gv = 20*log10(abs(app.Gv(1:size(app.Gv,1)/2,:))); %Convert the amplitude of the TF matrix to dB            Uthr = 0;  %Define the upper bound for the threshold (dB)
            app.Gv(app.Gv < app.Lthr | app.Gv > Uthr) = -Inf; % Set the undesired indexes to -Inf
            
            app.ComputeParametersButton.Text = 'Compute Parameters'; % Update the button text
            app.UpdateDiagramButtonPushed; % Call the Diagram plotting function
            app.CpFlag = 1; % Update the Computed Diagram Flag
        end

        % Button pushed function: UpdateDiagramButton
        function UpdateDiagramButtonPushed(app, event)
            cla(app.UIAxes); % Clear the diagram display
            %% STFT Display %%
            app.minNote = app.LowestNoteDropDown_2.Value; % Get the minimum displayed note based on user's selection
            app.maxNote = app.HighestNoteDropDown_2.Value; % Get the maximum displayed note based on user's selection
            fmin = app.fnotes(app.notes == app.minNote,1); % Find the smallest frequency based on the minimum note
            fmax = app.fnotes(app.notes == app.maxNote,1); % Find the highest frequency based on the maximum note
            
            t = 1/app.Fs:1/app.Fs:app.Lold/app.Fs; % Compute the time instants 
            
            lenSTFT = sum(app.A); % Compute the length of the STFT
            occSpace = ceil(app.Lold*size(app.Gv,2)/lenSTFT); % Compute the number of rows effectively used by the STFT
            f = linspace(0,app.Fs/2,size(app.Gv,1)); % Compute the frequency axis values 
            ifmin = find(abs(fmin-f') == min(abs(fmin-f'))); % Compute the index related to the minimum frequency
            ifmax = find(abs(fmax-f') == min(abs(fmax-f'))); % Compute the index related to the maximum frequency
            
            app.Dir = app.NotesSequenceDropDown_2.Value; % Get the diagram notes direction based on the user's selection

            if app.Dir == "Higher up" %Update the Y-axis display direction
                set(app.UIAxes, 'YDir', 'Normal');
            else
                set(app.UIAxes, 'YDir', 'Reverse');
            end
            
            colormap(app.UIAxes, jet(256)); % Define the color scale (follow a standard)
            imagesc(app.UIAxes,[t(1),t(end)],[f(ifmin), f(ifmax)],app.Gv(ifmin:ifmax, 1:occSpace));   %Graphical representation of the STFT:
            title(app.UIAxes,'Short-Time Fourier Transform Representation of the Signal'); %Define the title for the figure
            xlabel(app.UIAxes,'Time (s)'); % Define the label for the x-axis of the figure
            ylabel(app.UIAxes,'Notes');   %Define the label for the y-axis of the figure
            hold(app.UIAxes,"on"); % Hold the plot
            
            k1 = f(ifmin); % Auxiliary variable, smallest frequency according to the diagram frequency display
            ind1 = find(abs(k1-app.fnotes') == min(abs(k1-app.fnotes')));
            k2 = f(ifmax); % The same, but for the maximum frequency
            ind2 = find(abs(k2-app.fnotes') == min(abs(k2-app.fnotes')));
            
            for i = ind1:ind2 % Plot white lines based on the notes being displayed
                s = plot(app.UIAxes, linspace(0,t(end),10), app.fnotes(i)*ones(1, 10), '-w');
                dtt = s.DataTipTemplate; % Get the DataTip information of this plot
                dtt.DataTipRows(1).Label = 'Time'; % Define the first label as Time
                dtt.DataTipRows(2).Label = 'Freq'; % Define the second label as Frequency
                dtt.DataTipRows(3).Label = 'Note'; % Create a third DataTip called 'Note'
                dtt.DataTipRows(3).Value = repmat(app.notes(i),1,10); % Attribute the correct note to that DataTip
            end
            yticks(app.UIAxes, app.fnotes(ind1:ind2)); % Redefine the Y-ticks based on the notes frequencies
            yticklabels(app.UIAxes, app.notes(ind1:ind2)); % Update the Y-ticks based on the notes names
            axis(app.UIAxes, [t(1),t(end), fmin, fmax]); % Define the displayed axis
            enableDefaultInteractivity(app.UIAxes); % Enable the interactions such as zooming, pan, exporting figure, etc.
            app.UIAxes.Toolbar.Visible = 'on'; % Display the toolbar at the northeast corner
            
            app.StatusEditField.Value = 'Computed'; % Update the status message
            app.ComputeParametersButton.Enable = 'off'; % 
            app.UpdateDiagramButton.Enable = 'off';
            
            app.PlaySelectedAudioButton.Enable = 'on';
            app.SelectFileButton.Enable = 'on';
            app.LowestNoteDropDown_2.Enable = 'on';
            app.HighestNoteDropDown_2.Enable = 'on';
            app.MinTimeSlider.Enable = 'on';
            app.MaxTimeSlider.Enable = 'on';
            app.StartRecordingButton.Enable = 'on';
            app.NotesSequenceDropDown_2.Enable = 'on';
            app.SpectreThresholddBEditField_2.Enable = 'on';
            app.TargetRateHzDropDown.Enable = 'on';
            app.OpenDiagraminaNewWindowButton.Enable = 'on';
        end

        % Value changed function: SpectreThresholddBEditField_2
        function SpectreThresholddBEditField_2ValueChanged(app, event)
            TargetRate = str2double(app.TargetRateHzDropDown.Value); % Read the sampling rate based on user's input
            if app.CpFlag == 1 % Check if the Spectrogram was computed already 
                ciMin = floor(app.MinTimeSlider.Value*app.Fs); %Current iMin
                ciMax = floor(app.MaxTimeSlider.Value*app.Fs); %Current iMax
                if TargetRate ~= app.Fs || app.Lthr ~= app.SpectreThresholddBEditField_2.Value ...
                    || app.iMin ~= ciMin || app.iMax ~= ciMax % If there are changes in these parameters, compute again the Spectrogram
                    app.StatusEditField.Value = 'Press Compute Parameters'; % Update the status
                    app.ComputeParametersButton.Enable = 'on'; % Enable the button to Compute the spectrogram again
                    app.UpdateDiagramButton.Enable = 'off'; % Disable the update diagram button
                else % If there is no change:
                    app.ComputeParametersButton.Enable = 'off'; % Disable the button 
                    app.UpdateDiagramButton.Enable = 'on'; % Enable the button
                    
                    if app.CpFlag == 1 && app.ComputeParametersButton.Enable == "off" % Check if the Compute Parameters Button is disabled
                        cminNote = app.LowestNoteDropDown_2.Value; % Read the user's selection 
                        cmaxNote = app.HighestNoteDropDown_2.Value;
                        cDir = app.NotesSequenceDropDown_2.Value; 
                        if cminNote ~= convertCharsToStrings(app.minNote) || cmaxNote ~= convertCharsToStrings(app.maxNote) ...
                                || cDir ~= convertCharsToStrings(app.Dir) % if there is change in these parameters
                            app.StatusEditField.Value = "Press Update Diagram"; % Tell the user to update the diagram
                            app.UpdateDiagramButton.Enable = "on";
                        else
                            app.StatusEditField.Value = "Computed"; %Otherwise, do nothing
                            app.UpdateDiagramButton.Enable = "off";
                        end
                    end
                end
            end
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [file,path] = uigetfile({'*.wav';'*.mp3'; '*.m4a'; '*.mp4'}); %Open an UI window asking the user to input the file
            if isequal(file,0) % If no file was selected...
               app.StatusEditField.Value = 'Press Select File';
            else
               app.filename = fullfile(path,file); % Obtain the file information
               
               [y,app.fs] = audioread(app.filename); % Read the audio file
               
               if size(y,2) == 2 % Check if the file is stereo or mono
                   app.P = y(:,1) + y(:,2); % Add up stereo channels
               else
                   app.P = y(:,1);
               end
               
               TargetRate = str2double(app.TargetRateHzDropDown.Value); % Get the resampling rate
               app.sliderWindow = 2^(nextpow2(TargetRate/2)-1)/TargetRate; % Compute the slider window time based on the information
               
               % Unlock GUI features
               app.ComputeParametersButton.Enable = "on";
               app.StatusEditField.Value = 'File Selected';
               app.FileLocationLabel.Text = ['File Location: ', app.filename];
               app.FileLocationLabel.Visible = "on";
               app.TargetRateHzDropDown.Enable = "on";
               app.LowestNoteDropDown_2.Enable = "on";
               app.HighestNoteDropDown_2.Enable = "on";
               app.SpectreThresholddBEditField_2.Enable = "on";
               app.PlaySelectedAudioButton.Enable = 'on';
               app.NotesSequenceDropDown_2.Enable = 'on';
               app.UpdateDiagramButton.Enable = 'off';
               
               % Plot the input signal
               cla(app.UIAxes_2);
               plot(app.UIAxes_2,linspace(0,length(app.P)/app.fs,length(app.P)),app.P);
               plot(app.UIAxes_2,linspace(0,length(app.P)/app.fs,length(app.P)),app.P);
               app.UIAxes_2.Children(2,1).Color = [0.7,0.7,0.7];
               axis(app.UIAxes_2,[0,length(app.P)/app.fs,min(app.P),max(app.P)]);
               
               % Update the sliders
               app.MinTimeSlider.Limits = [0,length(app.P)/app.fs];
               app.MinTimeSlider.Value = 0;
               app.indMin = floor(app.MinTimeSlider.Value*app.fs);
               
               app.MaxTimeSlider.Limits = [0,length(app.P)/app.fs];
               app.MaxTimeSlider.Value = length(app.P)/app.fs;
               app.indMax = floor(app.MaxTimeSlider.Value*app.fs);
               
               % Enable the sliders
               app.MinTimeSlider.Enable = 'on';
               app.MaxTimeSlider.Enable = 'on';
               app.CpFlag = 0; % Reset the flag
               
               if app.indMax - app.indMin > app.maxProcTime*app.fs
                   app.MaxTimeSlider.Value = app.maxProcTime; 
                   app.indMax = floor(app.MaxTimeSlider.Value*app.fs);
                   app.UIAxes_2.Children(1,1).YData(app.indMax+1:end) = 0;
               end
               app.StatusEditField.Value = 'Press Compute Parameters';
            end
            figure(app.SuperSpectrogramUIFigure);
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            cla(app.UIAxes2);
            cla(app.UIAxes_3);
            app.StartRecordingButton.Enable = 'off';
            app.SamplingRateHzDropDown.Enable = 'off';
            app.LowestNoteDropDown.Enable = 'off';
            app.HighestNoteDropDown.Enable = 'off';
            app.RecordingTimesDropDown.Enable = 'off';
            app.RefreshRatesDropDown.Enable = 'off';
            app.RecalculateSpectrumButton.Enable = 'off';
            app.SpectreThresholddBEditField.Enable = 'off';
            app.StopRecordingButton.Enable = 'on';
            app.RefreshDiagramButton.Enable = 'off';
            app.PlaySelectedAudioButton_2.Enable = 'off';
            app.NotesSequenceDropDown.Enable = 'off';
            app.ExportRecordingasWavButton.Enable = 'off';
            app.MinTimeSlider_2.Enable = 'off';
            app.MaxTimeSlider_2.Enable = 'off';
            app.MicrophoneDropDown.Enable = 'off';
            app.SpeakerDropDown.Enable = 'off';
            app.PlaySelectedAudioButton.Enable = 'off';
            
            app.stopRec = 0;
            
            rFs = str2double(app.SamplingRateHzDropDown.Value);
            app.recF = rFs;
            
            recwindowSize = 2^(nextpow2(rFs/2)-1);
            sigma = recwindowSize/2^4;
            gwin = GenGaussianWindow(app,sigma);
            app.recsliderWindow = recwindowSize/rFs;
            
            rt = str2double(app.RecordingTimesDropDown.Value); % Get recording time
            
            rr = str2double(app.RefreshRatesDropDown.Value); % Get refresh rate
            
            app.recLthr = app.SpectreThresholddBEditField.Value;
            
            y = zeros(ceil(rFs*rt),1);
            
            %deviceReader = audioDeviceReader(rFs,recwindowSize);
            app.deviceReader.SampleRate = rFs;
            app.deviceReader.SamplesPerFrame = recwindowSize;
            app.deviceReader.Device = app.MicrophoneDropDown.Value;
            
            colormap(app.UIAxes2, jet(256));
            hold(app.UIAxes2, 'on');
            enableDefaultInteractivity(app.UIAxes2);
            app.UIAxes2.Toolbar.Visible = 'on'; 
            
            f = linspace(0,rFs/2,recwindowSize/2); % Compute the frequency axis values 
            
            app.recminNote = app.LowestNoteDropDown.Value;
            app.recmaxNote = app.HighestNoteDropDown.Value;
            fmin = app.fnotes(app.notes == app.recminNote,1);
            fmax = app.fnotes(app.notes == app.recmaxNote,1);
            
            ifmin = find(abs(fmin-f') == min(abs(fmin-f'))); % Compute the index related to the minimum frequency
            ifmax = find(abs(fmax-f') == min(abs(fmax-f'))); % Compute the index related to the maximum frequency
            
            k1 = f(ifmin);
            ind1 = find(abs(k1-app.fnotes') == min(abs(k1-app.fnotes')));
            k2 = f(ifmax);
            ind2 = find(abs(k2-app.fnotes') == min(abs(k2-app.fnotes')));
            
            axis(app.UIAxes2,[0,rr,fmin,fmax]);
            
            yticks(app.UIAxes2, app.fnotes(ind1:ind2)); % Redefine the y-axis ticks as the frequencies of the musical notes
            yticklabels(app.UIAxes2, app.notes(ind1:ind2)); % Redefine the y-axis tick labels as the name of the musical notes
            
            app.recDir = app.NotesSequenceDropDown.Value;
            
            if app.recDir == "Higher up"
                set(app.UIAxes2, 'YDir', 'Normal');
            else
                set(app.UIAxes2, 'YDir', 'Reverse');
            end
                       
            pt = 0; % Partial time
            i = 1; % Counter for the refresh rate
            pl = 0; % Partial length
            
            tic;
            while toc < rt
                mySignal = app.deviceReader();
                pause(0.1);
                if app.stopRec == 1
                    break;
                end
                y(pl+1:pl+length(mySignal),1) = mySignal;
                pl = pl + length(mySignal);
                if toc > i*rr
                    i = i+1;
                    axis(app.UIAxes2,[toc,i*rr,fmin,fmax]);
                end
                Gi = recSTFT(app, mySignal',sigma,app.recLthr,gwin);
                imagesc(app.UIAxes2, [pt,toc],[f(ifmin),f(ifmax)],Gi(ifmin:ifmax,:));   
                pt = toc;
            end
            
            if app.stopRec == 1
               pl = pl + length(mySignal);
               y(pl:end) = []; 
               rt = length(y)/rFs;
            end
            
            for i = ind1:ind2
                s = plot(app.UIAxes2, linspace(0,rt,10), app.fnotes(i)*ones(1, 10), '-w');
                dtt = s.DataTipTemplate; % Get the DataTip information of this plot
                dtt.DataTipRows(1).Label = 'Time'; % Define the first label as Time
                dtt.DataTipRows(2).Label = 'Freq'; % Define the second label as Frequency
                dtt.DataTipRows(3).Label = 'Note'; % Create a third DataTip called 'Note'
                dtt.DataTipRows(3).Value = repmat(app.notes(i),1,10); % Attribute the correct note to that DataTip
            end
            
            app.ry = y; % Recorded y signal
            
            axis(app.UIAxes2,[0,rt,fmin,fmax]);
            release(app.deviceReader);
            app.StartRecordingButton.Enable = "on";
            app.SamplingRateHzDropDown.Enable = 'on';
            app.LowestNoteDropDown.Enable = 'on';
            app.HighestNoteDropDown.Enable = 'on';
            app.RecordingTimesDropDown.Enable = 'on';
            app.RefreshRatesDropDown.Enable = 'on';
            app.RecalculateSpectrumButton.Enable = 'on';
            app.SpectreThresholddBEditField.Enable = 'on';
            app.StopRecordingButton.Enable = 'off';
            app.PlaySelectedAudioButton_2.Enable = 'on';
            app.NotesSequenceDropDown.Enable = 'on';
            app.ExportRecordingasWavButton.Enable = 'on';
            app.MicrophoneDropDown.Enable = 'on';
            app.SpeakerDropDown.Enable = 'on';
            if isempty(app.P)~= true
                app.PlaySelectedAudioButton.Enable = 'on';
            end
            
            recText = ['Audio recorded at ', num2str(app.recF), ' Hz'];
            app.RecFreqDisplay.Visible = 'on';
            app.RecFreqDisplay.Text = recText;
            
            cla(app.UIAxes_3);
            plot(app.UIAxes_3,linspace(0,length(app.ry)/app.recF,length(app.ry)),app.ry);
            plot(app.UIAxes_3,linspace(0,length(app.ry)/app.recF,length(app.ry)),app.ry);
            app.UIAxes_3.Children(2,1).Color = [0.7,0.7,0.7];
            axis(app.UIAxes_3,[0,length(app.ry)/app.recF,min(app.ry),max(app.ry)]);
            
            app.MinTimeSlider_2.Limits = [0,length(app.ry)/app.recF];
            app.MinTimeSlider_2.Value = 0;
            app.recindMin = floor(app.MinTimeSlider_2.Value*app.recF);
            
            app.MaxTimeSlider_2.Limits = [0,length(app.ry)/app.recF];
            app.MaxTimeSlider_2.Value = length(app.ry)/app.recF;
            app.recindMax = floor(app.MaxTimeSlider_2.Value*app.recF);
            
            app.MinTimeSlider_2.Enable = 'on';
            app.MaxTimeSlider_2.Enable = 'on';
            
            if app.recindMax - app.recindMin > app.maxProcTime*app.recF
               app.MaxTimeSlider_2.Value = app.maxProcTime; 
               app.recindMax = floor(app.MaxTimeSlider_2.Value*app.recF);
               app.UIAxes_3.Children(1,1).YData(app.recindMax+1:end) = 0;
            end
            
            app.RpFlag = 0;
            
        end

        % Value changed function: SamplingRateHzDropDown
        function SamplingRateHzDropDownValueChanged(app, event)
            rFs = str2double(app.SamplingRateHzDropDown.Value);
            maxFreq = rFs/2;
            indmN = find(abs(maxFreq-app.fnotes') == min(abs(maxFreq-app.fnotes')));
            app.HighestNoteDropDown.Items = app.notes(2:indmN-1,1);
            
            if (app.notes(2:indmN-1,1) == app.lastHnote) == 0
                app.HighestNoteDropDown.Value = app.notes(indmN-1,1);
                app.lastHnote = app.HighestNoteDropDown.Value;
            
            end
            app.LowestNoteDropDown.Items = app.notes(1:indmN-2,1);
            
            if (app.notes(1:indmN-2,1) == app.lastLnote) == 0
                app.LowestNoteDropDown.Value = app.notes(1,1);
                app.lastLnote = app.LowestNoteDropDown.Value;
            end
            
            if app.SamplingRateHzDropDown.Value == "8820"
                app.RecordingTimesDropDown.Items = ["5"; "10"; "15"; "20"; "30"];
            elseif app.SamplingRateHzDropDown.Value == "4410"
                app.RecordingTimesDropDown.Items = ["5"; "10"; "15"; "20"; "30"; "40"; "60"];
            else
                app.RecordingTimesDropDown.Items = ["5"; "10"; "15"; "20"; "30"; "40"; "60"; "120"];
            end
            
            TargetRate = str2double(app.SamplingRateHzDropDown.Value);
            
            if app.RpFlag == 1
                ciMin = floor(app.MinTimeSlider_2.Value*app.recF); %Current iMin
                ciMax = floor(app.MaxTimeSlider_2.Value*app.recF); %Current iMax
                if TargetRate ~= app.recF || app.recLthr ~= app.SpectreThresholddBEditField.Value ...
                    || app.reciMin ~= ciMin || app.reciMax ~= ciMax
                    app.RecalculateSpectrumButton.Enable = 'on';
                    app.RefreshDiagramButton.Enable = 'off';
                else
                    app.RecalculateSpectrumButton.Enable = 'off';
                    app.RefreshDiagramButton.Enable = 'on';
                    
                    if app.RpFlag == 1 && app.RecalculateSpectrumButton.Enable == "off"
                        cminNote = app.LowestNoteDropDown.Value;
                        cmaxNote = app.HighestNoteDropDown.Value;
                        cDir = app.NotesSequenceDropDown.Value;
                        if cminNote ~= convertCharsToStrings(app.recminNote) || cmaxNote ~= convertCharsToStrings(app.recmaxNote) ...
                                || cDir ~= convertCharsToStrings(app.recDir)
                            app.RefreshDiagramButton.Enable = "on";
                        else
                            app.RefreshDiagramButton.Enable = "off";
                        end
                    end
                end
            end
            
            
        end

        % Value changed function: LowestNoteDropDown
        function LowestNoteDropDownValueChanged(app, event)
            rFs = str2double(app.SamplingRateHzDropDown.Value);
            maxFreq = rFs/2;
            
            indmaxNote = find(abs(maxFreq-app.fnotes') == min(abs(maxFreq-app.fnotes')));
            
            cminNote = app.LowestNoteDropDown.Value;
            indminNote = find(app.notes == cminNote);
            
            app.HighestNoteDropDown.Items = app.notes(indminNote+1:indmaxNote,1); 
            app.lastLnote = app.LowestNoteDropDown.Value;
            
            if app.RpFlag == 1 && app.RecalculateSpectrumButton.Enable == "off"
                cDir = app.NotesSequenceDropDown.Value;
                cminNote = app.LowestNoteDropDown.Value;
                cmaxNote = app.HighestNoteDropDown.Value;
                if cminNote ~= convertCharsToStrings(app.recminNote) || cmaxNote ~= convertCharsToStrings(app.recmaxNote) ...
                        || cDir ~= convertCharsToStrings(app.recDir)
                    app.RefreshDiagramButton.Enable = "on";
                else
                    app.RefreshDiagramButton.Enable = "off";
                end
            end
        end

        % Value changed function: HighestNoteDropDown
        function HighestNoteDropDownValueChanged(app, event)
            cmaxNote = app.HighestNoteDropDown.Value;
            indmaxNote = find(app.notes == cmaxNote);
            app.LowestNoteDropDown.Items = app.notes(1:indmaxNote-1,1);
            app.lastHnote = app.HighestNoteDropDown.Value;
            
            if app.RpFlag == 1 && app.RecalculateSpectrumButton.Enable == "off"
                cDir = app.NotesSequenceDropDown.Value;
                cminNote = app.LowestNoteDropDown.Value;
                cmaxNote = app.HighestNoteDropDown.Value;
                if cminNote ~= convertCharsToStrings(app.recminNote) || cmaxNote ~= convertCharsToStrings(app.recmaxNote) ...
                        || cDir ~= convertCharsToStrings(app.recDir)
                    app.RefreshDiagramButton.Enable = "on";
                else
                    app.RefreshDiagramButton.Enable = "off";
                end
            end
        end

        % Value changed function: LowestNoteDropDown_2
        function LowestNoteDropDown_2ValueChanged(app, event)
            TargetRate = str2double(app.TargetRateHzDropDown.Value);
            maxFreq = TargetRate/2;
            
            cminNote = app.LowestNoteDropDown_2.Value;

            indminNote = find(app.notes == cminNote);
            indmaxNote = find(abs(maxFreq-app.fnotes') == min(abs(maxFreq-app.fnotes')));
            
            app.HighestNoteDropDown_2.Items = app.notes(indminNote+1:indmaxNote,1);  
            app.lastLnote2 = app.LowestNoteDropDown_2.Value;

            if app.CpFlag == 1 && app.ComputeParametersButton.Enable == "off"
                cmaxNote = app.HighestNoteDropDown_2.Value;
                cDir = app.NotesSequenceDropDown_2.Value;
                if cminNote ~= convertCharsToStrings(app.minNote) || cmaxNote ~= convertCharsToStrings(app.maxNote) ...
                        || cDir ~= convertCharsToStrings(app.Dir)
                    app.StatusEditField.Value = "Press Update Diagram";
                    app.UpdateDiagramButton.Enable = "on";
                else
                    app.StatusEditField.Value = "Computed";
                    app.UpdateDiagramButton.Enable = "off";
                end
            end
            
        end

        % Value changed function: HighestNoteDropDown_2
        function HighestNoteDropDown_2ValueChanged(app, event)

            cmaxNote = app.HighestNoteDropDown_2.Value;
            indmaxNote = find(app.notes == cmaxNote);
            
            app.LowestNoteDropDown_2.Items = app.notes(1:indmaxNote-1,1);
            app.lastHnote2 = app.HighestNoteDropDown_2.Value;
            
            if app.CpFlag == 1 && app.ComputeParametersButton.Enable == "off"
                cminNote = app.LowestNoteDropDown_2.Value;
                cDir = app.NotesSequenceDropDown_2.Value;
                if cminNote ~= convertCharsToStrings(app.minNote) || cmaxNote ~= convertCharsToStrings(app.maxNote) ...
                        || cDir ~= convertCharsToStrings(app.Dir)
                    app.StatusEditField.Value = "Press Update Diagram";
                    app.UpdateDiagramButton.Enable = "on";
                else
                    app.StatusEditField.Value = "Computed";
                    app.UpdateDiagramButton.Enable = "off";
                end
            end
        end

        % Value changed function: RecordingTimesDropDown
        function RecordingTimesDropDownValueChanged(app, event)
            rt = str2double(app.RecordingTimesDropDown.Value);
            if rt == 5
                app.RefreshRatesDropDown.Items = "5";
                app.SamplingRateHzDropDown.Items = ["1102.5"; "2205"; "4410"; "8810"];
            elseif rt == 10
                app.RefreshRatesDropDown.Items = ["5";"10"];
                app.SamplingRateHzDropDown.Items = ["1102.5"; "2205"; "4410"; "8810"];
            elseif rt == 15
                app.RefreshRatesDropDown.Items = ["5";"15"];
                app.SamplingRateHzDropDown.Items = ["1102.5"; "2205"; "4410"; "8810"];
            elseif rt == 20
                app.RefreshRatesDropDown.Items = ["5";"10";"20"];
                app.SamplingRateHzDropDown.Items = ["1102.5"; "2205"; "4410"; "8810"];
            elseif rt == 30
                app.RefreshRatesDropDown.Items = ["5";"10";"15";"30"];
                app.SamplingRateHzDropDown.Items = ["1102.5"; "2205"; "4410"; "8810"];
            elseif rt == 40
                app.RefreshRatesDropDown.Items = ["5"; "10"; "20"; "40"];
                app.SamplingRateHzDropDown.Items = ["1102.5"; "2205"; "4410"];
            elseif rt == 60
                app.RefreshRatesDropDown.Items = ["5";"10";"15";"20";"30";"60"];
                app.SamplingRateHzDropDown.Items = ["1102.5"; "2205"; "4410"];
            elseif rt == 120
                app.RefreshRatesDropDown.Items = ["5";"10";"15";"20";"30";"60";"120"];
                app.SamplingRateHzDropDown.Items = ["1102.5"; "2205"];
            end
            
        end

        % Button pushed function: RecalculateSpectrumButton
        function RecalculateSpectrumButtonPushed(app, event)
            cla(app.UIAxes2);
            
            % Disable GUI controls
            app.StartRecordingButton.Enable = 'off';
            app.SamplingRateHzDropDown.Enable = 'off';
            app.LowestNoteDropDown.Enable = 'off';
            app.HighestNoteDropDown.Enable = 'off';
            app.RecordingTimesDropDown.Enable = 'off';
            app.RefreshRatesDropDown.Enable = 'off';
            app.RecalculateSpectrumButton.Enable = 'off';
            app.SpectreThresholddBEditField.Enable = 'off';
            app.NotesSequenceDropDown.Enable = 'off';
            app.PlaySelectedAudioButton_2.Enable = 'off';
            app.ExportRecordingasWavButton.Enable = 'off';
            app.MicrophoneDropDown.Enable = 'off';
            app.SpeakerDropDown.Enable = 'off';
            
            app.RecalculateSpectrumButton.Text = 'Computing...';
            pause(0.001);
            
            rFs = str2double(app.SamplingRateHzDropDown.Value);
            if rFs ~= app.recF
                if rFs > app.recF
                    yr = interp(app.ry,round(rFs/app.recF));
                elseif rFs < app.recF
                    yr = decimate(app.ry, round(app.recF/rFs));
                end
            else
                yr = app.ry;
            end
            
            app.procF = rFs;
            windowSize = 2^(nextpow2(rFs/2)-1);
            sigma = windowSize/2^4;
            gwin = GenGaussianWindow(app,sigma);
            
            l = nextpow2(sigma);
            app.recLthr = app.SpectreThresholddBEditField.Value;
                       
            app.reciMin = floor(app.MinTimeSlider_2.Value*app.procF);
            app.reciMax = floor(app.MaxTimeSlider_2.Value*app.procF);
            
            yr = yr(app.reciMin+1:app.reciMax);
            
            if floor(app.recsliderWindow*rFs) == length(yr)
                yr(end+1) = 0;
            end
            
            app.oldL = length(yr);
                        
            st = 0; %stop condition
            Lr = app.oldL;
            app.A = 0;
            while st ~= 1
               app.A = [app.A, 2^(nextpow2(Lr)-1)];
               Lr = Lr - 2^(nextpow2(Lr)-1);
               if Lr <= 2^(l+4)
                   st = 1;
                   app.A = [app.A,2^(l+4)];
               end
            end
            %Idea: Create a sliding window to compensate for the window border effect

            y = [yr(:,1); zeros(sum(app.A)-app.oldL,1)]; % Complete y with zeros
            app.Gv = zeros(16*sigma,sum(app.A)/8); % Initialize Gv
            for i = 2:length(app.A)
                sig = y(sum(app.A(1:i-1)) + 1 : sum(app.A(1:i)));
                app.Gv(:,sum(app.A(1:i-1))/8 + 1:sum(app.A(1:i))/8) = app.STFT(sig',sigma,gwin); % Compute the STFT
            end
            
            for i = 2:length(app.A)-1
                sig = y(sum(app.A(1:i)) - 2^(l+3)+1 : sum(app.A(1:i)) + 2^(l+3));
                Gi = app.STFT(sig',sigma,gwin);
                app.Gv(:,sum(app.A(1:i))/8 - 2^(l-1)+1:sum(app.A(1:i))/8 + 2^(l-1)) = Gi(:,2^(l-1)+1:2^l + 2^(l-1)); % Compute the STFT
            end
            
            Uthr = 0;
            app.Gv = 20*log10(abs(app.Gv(1:size(app.Gv,1)/2,:))); %Convert the amplitude of the TF matrix to dB            Uthr = 0;  %Define the upper bound for the threshold (dB)
            app.Gv(app.Gv < app.recLthr | app.Gv > Uthr) = -Inf; % Set the undesired indexes to -Inf
            
            % Display the improved image
            
            cla(app.UIAxes2);
            f = linspace(0,rFs/2,windowSize/2); % Compute the frequency axis values 
            
            app.recminNote = app.LowestNoteDropDown.Value;
            app.recmaxNote = app.HighestNoteDropDown.Value;
            fmin = app.fnotes(app.notes == app.recminNote,1);
            fmax = app.fnotes(app.notes == app.recmaxNote,1);
            
            ifmin = find(abs(fmin-f') == min(abs(fmin-f'))); % Compute the index related to the minimum frequency
            ifmax = find(abs(fmax-f') == min(abs(fmax-f'))); % Compute the index related to the maximum frequency
            
            k1 = f(ifmin);
            ind1 = find(abs(k1-app.fnotes') == min(abs(k1-app.fnotes')));
            k2 = f(ifmax);
            ind2 = find(abs(k2-app.fnotes') == min(abs(k2-app.fnotes')));
            
            imagesc(app.UIAxes2, [0,length(yr)/rFs],[f(ifmin),f(ifmax)],app.Gv(ifmin:ifmax,:));
            
            for i = ind1:ind2
                s = plot(app.UIAxes2, linspace(0,length(yr)/rFs,10), app.fnotes(i)*ones(1, 10), '-w');
                dtt = s.DataTipTemplate; % Get the DataTip information of this plot
                dtt.DataTipRows(1).Label = 'Time'; % Define the first label as Time
                dtt.DataTipRows(2).Label = 'Freq'; % Define the second label as Frequency
                dtt.DataTipRows(3).Label = 'Note'; % Create a third DataTip called 'Note'
                dtt.DataTipRows(3).Value = repmat(app.notes(i),1,10); % Attribute the correct note to that DataTip
    
            end
            axis(app.UIAxes2, [0,length(yr)/rFs, fmin, fmax]);
            yticks(app.UIAxes2, app.fnotes(ind1:ind2)); % Redefine the y-axis ticks as the frequencies of the musical notes
            yticklabels(app.UIAxes2, app.notes(ind1:ind2)); % Redefine the y-axis tick labels as the name of the musical notes
            
            app.recDir = app.NotesSequenceDropDown.Value;
            
            if app.recDir == "Higher up"
                set(app.UIAxes2, 'YDir', 'Normal');
            else
                set(app.UIAxes2, 'YDir', 'Reverse');
            end  
            
            %Reenable GUI controls
            app.StartRecordingButton.Enable = "on";
            app.SamplingRateHzDropDown.Enable = 'on';
            app.LowestNoteDropDown.Enable = 'on';
            app.HighestNoteDropDown.Enable = 'on';
            app.RecordingTimesDropDown.Enable = 'on';
            app.RefreshRatesDropDown.Enable = 'on';
            app.SpectreThresholddBEditField.Enable = 'on';
            app.NotesSequenceDropDown.Enable = 'on';
            app.PlaySelectedAudioButton_2.Enable = 'on';
            app.ExportRecordingasWavButton.Enable = 'on';
            app.MicrophoneDropDown.Enable = 'on';
            app.SpeakerDropDown.Enable = 'on';
            app.RecalculateSpectrumButton.Text = 'Recalculate Spectrum';
            app.RpFlag = 1;
            app.OpenDiagraminaNewWindowButton_2.Enable = 'on';
        end

        % Button pushed function: PlaySelectedAudioButton
        function PlaySelectedAudioButtonPushed(app, event)
            app.StartRecordingButton.Enable = 'off';
            app.PlaySelectedAudioButton.Enable = 'off';
            app.PlaySelectedAudioButton_2.Enable = 'off';
            pause(0.001);
            
            app.deviceWriter.SampleRate = app.fs; % Update the speaker sampling rate
            app.deviceWriter.Device = app.SpeakerDropDown.Value; % Use the device selected by the user
            app.deviceWriter.BufferSize = round(app.fs/10.7666);
            
            audioData = app.P(app.indMin+1:app.indMax);  % Get the data to be played
       
            st = floor(length(audioData)/(2*app.fs)); % Define how many multiples of double sampling rate are there
            rem = length(audioData) - st*2*app.fs; % Compute the remainder
            
            if rem > 0
                audioData(st*2*app.fs+rem+1:(st+1)*2*app.fs) = 0; % Fill with zeros
                st = st+1; % Increment the number of multiples
            end
            
            for i = 1:st % Play per parts (here the deviceWriter cannot play more samples than 2*app.fs
                app.deviceWriter(audioData((i-1)*2*app.fs+1:i*2*app.fs)); % Play the segment
            end

            release(app.deviceWriter); % Release the deviceWriter
            %soundsc(app.P(app.indMin+1:app.indMax),app.fs);
            
            app.StartRecordingButton.Enable = 'on';
            if isempty(app.P) ~= true
                app.PlaySelectedAudioButton.Enable = 'on';
            end
            if isempty(app.ry) ~= true
                app.PlaySelectedAudioButton_2.Enable = 'on';
            end
        end

        % Button pushed function: RefreshDiagramButton
        function RefreshDiagramButtonPushed(app, event)
            
            rFs = app.procF;
            
            windowSize = 2^(nextpow2(rFs/2)-1);
            
            cla(app.UIAxes2);
            
            f = linspace(0,rFs/2,windowSize/2); % Compute the frequency axis values 
            
            app.recminNote = app.LowestNoteDropDown.Value;
            app.recmaxNote = app.HighestNoteDropDown.Value;
            fmin = app.fnotes(app.notes == app.recminNote,1);
            fmax = app.fnotes(app.notes == app.recmaxNote,1);
            
            ifmin = find(abs(fmin-f') == min(abs(fmin-f'))); % Compute the index related to the minimum frequency
            ifmax = find(abs(fmax-f') == min(abs(fmax-f'))); % Compute the index related to the maximum frequency
            
            k1 = f(ifmin);
            ind1 = find(abs(k1-app.fnotes') == min(abs(k1-app.fnotes')));
            k2 = f(ifmax);
            ind2 = find(abs(k2-app.fnotes') == min(abs(k2-app.fnotes')));
            
          
            imagesc(app.UIAxes2, [0,app.oldL/rFs],[f(ifmin),f(ifmax)],app.Gv(ifmin:ifmax,:));
            
            for i = ind1:ind2
                s = plot(app.UIAxes2, linspace(0,app.oldL/rFs,10), app.fnotes(i)*ones(1, 10), '-w');
                dtt = s.DataTipTemplate; % Get the DataTip information of this plot
                dtt.DataTipRows(1).Label = 'Time'; % Define the first label as Time
                dtt.DataTipRows(2).Label = 'Freq'; % Define the second label as Frequency
                dtt.DataTipRows(3).Label = 'Note'; % Create a third DataTip called 'Note'
                dtt.DataTipRows(3).Value = repmat(app.notes(i),1,10); % Attribute the correct note to that DataTip
    
            end
            axis(app.UIAxes2, [0,app.oldL/rFs, fmin, fmax]);
            yticks(app.UIAxes2, app.fnotes(ind1:ind2)); % Redefine the y-axis ticks as the frequencies of the musical notes
            yticklabels(app.UIAxes2, app.notes(ind1:ind2)); % Redefine the y-axis tick labels as the name of the musical notes
                
            app.recDir = app.NotesSequenceDropDown.Value;
            
            if app.recDir == "Higher up"
                set(app.UIAxes2, 'YDir', 'Normal');
            else
                set(app.UIAxes2, 'YDir', 'Reverse');
            end
            app.RefreshDiagramButton.Enable = "off";
            app.OpenDiagraminaNewWindowButton_2.Enable = "on";
        end

        % Button pushed function: PlaySelectedAudioButton_2
        function PlaySelectedAudioButton_2Pushed(app, event)
            %Lock GUI functions
            app.StartRecordingButton.Enable = 'off';
            app.PlaySelectedAudioButton.Enable = 'off';
            app.PlaySelectedAudioButton_2.Enable = 'off';
            pause(0.001);
            
            app.deviceWriter.Device = app.SpeakerDropDown.Value; % Use the device selected by the user
            app.deviceWriter.SampleRate = app.recF; % Update the speaker sampling rate
            app.deviceWriter.BufferSize = round(app.recF/10.7666);
            
            audioData = app.ry(app.recindMin+1:app.recindMax);  % Get the data to be played
       
            st = floor(length(audioData)/(2*app.recF)); % Define how many multiples of double sampling rate are there
            rem = length(audioData) - st*2*app.recF; % Compute the remainder
            
            if rem > 0
                audioData(st*2*app.recF+rem+1:(st+1)*2*app.recF) = 0; % Fill with zeros
                st = st+1; % Increment the number of multiples
            end
            
            for i = 1:st % Play per parts (here the deviceWriter cannot play more samples than 2*Fs
                app.deviceWriter(audioData((i-1)*2*app.recF+1:i*2*app.recF)); % Play the segment
            end

            release(app.deviceWriter); % Release the deviceWriter
            %soundsc(app.ry(app.recindMin+1:app.recindMax),app.recF);
            
            % Reenable GUI functions
            app.StartRecordingButton.Enable = 'on';
            
            if isempty(app.P) ~= true
                app.PlaySelectedAudioButton.Enable = 'on';
            end
            if isempty(app.ry) ~= true
                app.PlaySelectedAudioButton_2.Enable = 'on';
            end
        end

        % Value changed function: SpectreThresholddBEditField
        function SpectreThresholddBEditFieldValueChanged(app, event)
            TargetRate = str2double(app.SamplingRateHzDropDown.Value);
            if app.RpFlag == 1
                ciMin = floor(app.MinTimeSlider_2.Value*app.recF); %Current iMin
                ciMax = floor(app.MaxTimeSlider_2.Value*app.recF); %Current iMax
                if TargetRate ~= app.recF || app.recLthr ~= app.SpectreThresholddBEditField.Value ...
                    || app.reciMin ~= ciMin || app.reciMax ~= ciMax
                    app.RecalculateSpectrumButton.Enable = 'on';
                    app.RefreshDiagramButton.Enable = 'off';
                else
                    app.RecalculateSpectrumButton.Enable = 'off';
                    app.RefreshDiagramButton.Enable = 'on';
                    
                    if app.RpFlag == 1 && app.RecalculateSpectrumButton.Enable == "off"
                        cminNote = app.LowestNoteDropDown.Value;
                        cmaxNote = app.HighestNoteDropDown.Value;
                        cDir = app.NotesSequenceDropDown.Value;
                        if cminNote ~= convertCharsToStrings(app.recminNote) || cmaxNote ~= convertCharsToStrings(app.recmaxNote) ...
                                || cDir ~= convertCharsToStrings(app.recDir)
                            app.RefreshDiagramButton.Enable = "on";
                        else
                            app.RefreshDiagramButton.Enable = "off";
                        end
                    end
                end
            end
        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed2(app, event)
            app.stopRec = 1;
        end

        % Value changed function: TargetRateHzDropDown
        function TargetRateHzDropDownValueChanged(app, event)
            TargetRate = str2double(app.TargetRateHzDropDown.Value);
            if app.CpFlag == 1
                ciMin = floor(app.MinTimeSlider.Value*app.Fs); %Current iMin
                ciMax = floor(app.MaxTimeSlider.Value*app.Fs); %Current iMax
                if TargetRate ~= app.Fs || app.Lthr ~= app.SpectreThresholddBEditField_2.Value ...
                    || app.iMin ~= ciMin || app.iMax ~= ciMax
                    app.StatusEditField.Value = 'Press Compute Parameters';
                    app.ComputeParametersButton.Enable = 'on';
                    app.UpdateDiagramButton.Enable = 'off';
                else
                    app.ComputeParametersButton.Enable = 'off';
                    app.UpdateDiagramButton.Enable = 'on';
                    
                    if app.CpFlag == 1 && app.ComputeParametersButton.Enable == "off"
                        cminNote = app.LowestNoteDropDown_2.Value;
                        cmaxNote = app.HighestNoteDropDown_2.Value;
                        cDir = app.NotesSequenceDropDown_2.Value;
                        if cminNote ~= convertCharsToStrings(app.minNote) || cmaxNote ~= convertCharsToStrings(app.maxNote) ...
                                || cDir ~= convertCharsToStrings(app.Dir)
                            app.StatusEditField.Value = "Press Update Diagram";
                            app.UpdateDiagramButton.Enable = "on";
                        else
                            app.StatusEditField.Value = "Computed";
                            app.UpdateDiagramButton.Enable = "off";
                        end
                    end
                end
            end
            maxFreq = TargetRate/2;
            indmN = find(abs(maxFreq-app.fnotes') == min(abs(maxFreq-app.fnotes')));
            app.HighestNoteDropDown_2.Items = app.notes(2:indmN-1,1);
            if (app.notes(2:indmN-1,1) == app.lastHnote2) == 0
                app.HighestNoteDropDown_2.Value = app.notes(indmN-1,1);
                app.lastHnote2 = app.HighestNoteDropDown_2.Value;
            end
            app.LowestNoteDropDown_2.Items = app.notes(1:indmN-2,1);
            if (app.notes(1:indmN-2,1) == app.lastLnote2) == 0
                app.LowestNoteDropDown_2.Value = app.notes(1,1);
                app.lastLnote2 = app.LowestNoteDropDown_2.Value;
            end
            app.sliderWindow = 2^(nextpow2(TargetRate/2)-1)/TargetRate;
        end

        % Value changed function: MinTimeSlider
        function MinTimeSliderValueChanged(app, event)
            MinSliderValue = app.MinTimeSlider.Value;
            
            if MinSliderValue + app.sliderWindow > app.MaxTimeSlider.Value
                app.MinTimeSlider.Value = app.MaxTimeSlider.Value - app.sliderWindow;
                app.indMin = floor(app.MinTimeSlider.Value*app.fs);
                app.UIAxes_2.Children(1,1).YData(1:app.indMin) = 0;
                app.UIAxes_2.Children(1,1).YData(app.indMin+1:app.indMax) = app.UIAxes_2.Children(2,1).YData(app.indMin+1:app.indMax);
            end
            
            if app.indMax - app.indMin > floor(app.maxProcTime*app.fs)
               delta = app.MaxTimeSlider.Value - app.MinTimeSlider.Value - app.maxProcTime;
               app.MinTimeSlider.Value = app.MinTimeSlider.Value + delta; 
               app.indMin = floor(app.MinTimeSlider.Value*app.fs);
               app.UIAxes_2.Children(1,1).YData(1:app.indMin) = 0;
            end
            
            TargetRate = str2double(app.TargetRateHzDropDown.Value);
            
            if app.CpFlag == 1
                ciMin = floor(app.MinTimeSlider.Value*app.Fs); %Current iMin
                ciMax = floor(app.MaxTimeSlider.Value*app.Fs); %Current iMax
                if TargetRate ~= app.Fs || app.Lthr ~= app.SpectreThresholddBEditField_2.Value ...
                    || app.iMin ~= ciMin || app.iMax ~= ciMax
                    app.StatusEditField.Value = 'Press Compute Parameters';
                    app.ComputeParametersButton.Enable = 'on';
                    app.UpdateDiagramButton.Enable = 'off';
                else
                    app.ComputeParametersButton.Enable = 'off';
                    app.UpdateDiagramButton.Enable = 'on';
                    if app.CpFlag == 1 && app.ComputeParametersButton.Enable == "off"
                        cminNote = app.LowestNoteDropDown_2.Value;
                        cmaxNote = app.HighestNoteDropDown_2.Value;
                        cDir = app.NotesSequenceDropDown_2.Value;
                        if cminNote ~= convertCharsToStrings(app.minNote) || cmaxNote ~= convertCharsToStrings(app.maxNote) ...
                                || cDir ~= convertCharsToStrings(app.Dir)
                            app.StatusEditField.Value = "Press Update Diagram";
                            app.UpdateDiagramButton.Enable = "on";
                        else
                            app.StatusEditField.Value = "Computed";
                            app.UpdateDiagramButton.Enable = "off";
                        end
                    end
                end
            end
        end

        % Value changed function: MaxTimeSlider
        function MaxTimeSliderValueChanged(app, event)
            MaxSliderValue = app.MaxTimeSlider.Value;
            if MaxSliderValue - app.sliderWindow < app.MinTimeSlider.Value
                app.MaxTimeSlider.Value = app.MinTimeSlider.Value + app.sliderWindow;
                app.indMax = floor(app.MaxTimeSlider.Value*app.fs);
                app.UIAxes_2.Children(1,1).YData(app.indMax+1:end) = 0;
                app.UIAxes_2.Children(1,1).YData(app.indMin+1:app.indMax) = app.UIAxes_2.Children(2,1).YData(app.indMin+1:app.indMax);
            end
            if app.indMax - app.indMin > floor(app.maxProcTime*app.fs)
               delta = app.MaxTimeSlider.Value - app.MinTimeSlider.Value - app.maxProcTime;
               app.MaxTimeSlider.Value = app.MaxTimeSlider.Value - delta; 
               app.indMax = floor(app.MaxTimeSlider.Value*app.fs);
               app.UIAxes_2.Children(1,1).YData(app.indMax+1:end) = 0;
            end
            
            TargetRate = str2double(app.TargetRateHzDropDown.Value);
            if app.CpFlag == 1
                ciMin = floor(app.MinTimeSlider.Value*app.Fs); %Current iMin
                ciMax = floor(app.MaxTimeSlider.Value*app.Fs); %Current iMax
                if TargetRate ~= app.Fs || app.Lthr ~= app.SpectreThresholddBEditField_2.Value ...
                    || app.iMin ~= ciMin || app.iMax ~= ciMax
                    app.StatusEditField.Value = 'Press Compute Parameters';
                    app.UpdateDiagramButton.Enable = 'off';
                    app.ComputeParametersButton.Enable = 'on';
                else
                    app.ComputeParametersButton.Enable = 'off';
                    app.UpdateDiagramButton.Enable = 'on';
                    if app.CpFlag == 1 && app.ComputeParametersButton.Enable == "off"
                        cminNote = app.LowestNoteDropDown_2.Value;
                        cmaxNote = app.HighestNoteDropDown_2.Value;
                        cDir = app.NotesSequenceDropDown_2.Value;
                        if cminNote ~= convertCharsToStrings(app.minNote) || cmaxNote ~= convertCharsToStrings(app.maxNote) ...
                                || cDir ~= convertCharsToStrings(app.Dir)
                            app.StatusEditField.Value = "Press Update Diagram";
                            app.UpdateDiagramButton.Enable = "on";
                        else
                            app.StatusEditField.Value = "Computed";
                            app.UpdateDiagramButton.Enable = "off";
                        end
                    end
                end
            end
        end

        % Value changing function: MinTimeSlider
        function MinTimeSliderValueChanging(app, event)
            MinSliderValue = event.Value;
            app.indMin = floor(MinSliderValue*app.fs);
            app.UIAxes_2.Children(1,1).YData(1:app.indMin) = 0;
            app.UIAxes_2.Children(1,1).YData(app.indMin+1:app.indMax) = app.UIAxes_2.Children(2,1).YData(app.indMin+1:app.indMax);
          
        end

        % Value changing function: MaxTimeSlider
        function MaxTimeSliderValueChanging(app, event)
            MaxSliderValue = event.Value;
            app.indMax = floor(MaxSliderValue*app.fs);
            app.UIAxes_2.Children(1,1).YData(app.indMax+1:end) = 0;
            app.UIAxes_2.Children(1,1).YData(app.indMin+1:app.indMax) = app.UIAxes_2.Children(2,1).YData(app.indMin+1:app.indMax);
        end

        % Value changed function: NotesSequenceDropDown_2
        function NotesSequenceDropDown_2ValueChanged(app, event)
  
            if app.CpFlag == 1 && app.ComputeParametersButton.Enable == "off"
                cDir = app.NotesSequenceDropDown_2.Value;
                cminNote = app.LowestNoteDropDown_2.Value;
                cmaxNote = app.HighestNoteDropDown_2.Value;
                if cminNote ~= convertCharsToStrings(app.minNote) || cmaxNote ~= convertCharsToStrings(app.maxNote) ...
                        || cDir ~= convertCharsToStrings(app.Dir)
                    app.StatusEditField.Value = "Press Update Diagram";
                    app.UpdateDiagramButton.Enable = "on";
                else
                    app.StatusEditField.Value = "Computed";
                    app.UpdateDiagramButton.Enable = "off";
                end
            end
            
        end

        % Button pushed function: ExportRecordingasWavButton
        function ExportRecordingasWavButtonPushed(app, event)
            [file,path] = uiputfile('*.wav');
            if isequal(file,0)
               %disp('User selected Cancel');
            else
               %disp(['User selected ', fullfile(path,file)]);
               fileinfo = fullfile(path,file);
               audiowrite(fileinfo,app.ry,app.recF);
            end
            figure(app.SuperSpectrogramUIFigure);
        end

        % Value changed function: MinTimeSlider_2
        function MinTimeSlider_2ValueChanged(app, event)
            MinSliderValue = app.MinTimeSlider_2.Value;
            
            if MinSliderValue + app.recsliderWindow > app.MaxTimeSlider_2.Value
                app.MinTimeSlider_2.Value = app.MaxTimeSlider_2.Value - app.recsliderWindow;
                app.recindMin = floor(app.MinTimeSlider_2.Value*app.recF);
                app.UIAxes_3.Children(1,1).YData(1:app.recindMin) = 0;
                app.UIAxes_3.Children(1,1).YData(app.recindMin+1:app.recindMax) = app.UIAxes_3.Children(2,1).YData(app.recindMin+1:app.recindMax);
            end
            
            if app.recindMax - app.recindMin > floor(app.maxProcTime*app.recF)
               delta = app.MaxTimeSlider_2.Value - app.MinTimeSlider_2.Value - app.maxProcTime;
               app.MinTimeSlider_2.Value = app.MinTimeSlider_2.Value + delta; 
               app.recindMin = floor(app.MinTimeSlider_2.Value*app.recF);
               app.UIAxes_3.Children(1,1).YData(1:app.recindMin) = 0;
            end
            
            TargetRate = str2double(app.SamplingRateHzDropDown.Value);
            if app.RpFlag == 1
                ciMin = floor(app.MinTimeSlider_2.Value*app.recF); %Current iMin
                ciMax = floor(app.MaxTimeSlider_2.Value*app.recF); %Current iMax
                if TargetRate ~= app.recF || app.recLthr ~= app.SpectreThresholddBEditField.Value ...
                    || app.reciMin ~= ciMin || app.reciMax ~= ciMax
                    app.RecalculateSpectrumButton.Enable = 'on';
                    app.RefreshDiagramButton.Enable = 'off';
                else
                    app.RecalculateSpectrumButton.Enable = 'off';
                    app.RefreshDiagramButton.Enable = 'on';
                    if app.RpFlag == 1 && app.RecalculateSpectrumButton.Enable == "off"
                        cminNote = app.LowestNoteDropDown.Value;
                        cmaxNote = app.HighestNoteDropDown.Value;
                        cDir = app.NotesSequenceDropDown.Value;
                        if cminNote ~= convertCharsToStrings(app.recminNote) || cmaxNote ~= convertCharsToStrings(app.recmaxNote) ...
                                || cDir ~= convertCharsToStrings(app.recDir)
                                app.RefreshDiagramButton.Enable = "on";
                        else
                            app.RefreshDiagramButton.Enable = "off";
                        end
                    end
                end
            end
        end

        % Value changing function: MinTimeSlider_2
        function MinTimeSlider_2ValueChanging(app, event)
            MinSliderValue = event.Value;
            app.recindMin = floor(MinSliderValue*app.recF);
            app.UIAxes_3.Children(1,1).YData(1:app.recindMin) = 0;
            app.UIAxes_3.Children(1,1).YData(app.recindMin+1:app.recindMax) = app.UIAxes_3.Children(2,1).YData(app.recindMin+1:app.recindMax);     
        end

        % Value changed function: MaxTimeSlider_2
        function MaxTimeSlider_2ValueChanged(app, event)
            MaxSliderValue = app.MaxTimeSlider_2.Value;
            
            if MaxSliderValue - app.recsliderWindow < app.MinTimeSlider_2.Value
                app.MaxTimeSlider_2.Value = app.MinTimeSlider_2.Value + app.recsliderWindow;
                app.recindMax = floor(app.MaxTimeSlider_2.Value*app.recF);
                app.UIAxes_3.Children(1,1).YData(app.recindMax+1:end) = 0;
                app.UIAxes_3.Children(1,1).YData(app.recindMin+1:app.recindMax) = app.UIAxes_3.Children(2,1).YData(app.recindMin+1:app.recindMax);
            end
            
            if app.recindMax - app.recindMin > floor(app.maxProcTime*app.recF)
               delta = app.MaxTimeSlider_2.Value - app.MinTimeSlider_2.Value - app.maxProcTime;
               app.MaxTimeSlider_2.Value = app.MaxTimeSlider_2.Value - delta; 
               app.recindMax = floor(app.MaxTimeSlider_2.Value*app.recF);
               app.UIAxes_3.Children(1,1).YData(app.recindMax+1:end) = 0;
            end
            
            TargetRate = str2double(app.SamplingRateHzDropDown.Value);
            if app.RpFlag == 1
                ciMin = floor(app.MinTimeSlider_2.Value*app.recF); %Current iMin
                ciMax = floor(app.MaxTimeSlider_2.Value*app.recF); %Current iMax
                if TargetRate ~= app.recF || app.recLthr ~= app.SpectreThresholddBEditField.Value ...
                    || app.reciMin ~= ciMin || app.reciMax ~= ciMax
                    app.RecalculateSpectrumButton.Enable = 'on';
                    app.RefreshDiagramButton.Enable = 'off';
                else
                    app.RecalculateSpectrumButton.Enable = 'off';
                    app.RefreshDiagramButton.Enable = 'on';
                    if app.RpFlag == 1 && app.RecalculateSpectrumButton.Enable == "off"
                        cminNote = app.LowestNoteDropDown.Value;
                        cmaxNote = app.HighestNoteDropDown.Value;
                        cDir = app.NotesSequenceDropDown.Value;
                        if cminNote ~= convertCharsToStrings(app.recminNote) || cmaxNote ~= convertCharsToStrings(app.recmaxNote) ...
                                || cDir ~= convertCharsToStrings(app.recDir)
                                app.RefreshDiagramButton.Enable = "on";
                        else
                            app.RefreshDiagramButton.Enable = "off";
                        end
                    end
                end
            end
            
        end

        % Value changing function: MaxTimeSlider_2
        function MaxTimeSlider_2ValueChanging(app, event)
            MaxSliderValue = event.Value;
            app.recindMax = floor(MaxSliderValue*app.recF);
            app.UIAxes_3.Children(1,1).YData(app.recindMax+1:end) = 0;
            app.UIAxes_3.Children(1,1).YData(app.recindMin+1:app.recindMax) = app.UIAxes_3.Children(2,1).YData(app.recindMin+1:app.recindMax);
            
        end

        % Value changed function: NotesSequenceDropDown
        function NotesSequenceDropDownValueChanged(app, event)
            if app.RpFlag == 1 && app.RecalculateSpectrumButton.Enable == "off"
                cDir = app.NotesSequenceDropDown.Value;
                cminNote = app.LowestNoteDropDown.Value;
                cmaxNote = app.HighestNoteDropDown.Value;
                if cminNote ~= convertCharsToStrings(app.recminNote) || cmaxNote ~= convertCharsToStrings(app.recmaxNote) ...
                        || cDir ~= convertCharsToStrings(app.recDir)
                    app.RefreshDiagramButton.Enable = "on";
                else
                    app.RefreshDiagramButton.Enable = "off";
                end
            end  
        end

        % Button pushed function: OpenDiagraminaNewWindowButton
        function OpenDiagraminaNewWindowButtonPushed(app, event)
            delete(app.ExternalUIFigure);
            
            % Create SuperSpectrogramUIFigure and hide until all components are created
            app.ExternalUIFigure = uifigure('Visible', 'off');
            app.ExternalUIFigure.Position = [100 100 738 580];
            app.ExternalUIFigure.Name = 'Super Spectrogram';

            % Create UIAxes
            app.UIAxes3 = uiaxes(app.ExternalUIFigure);
            title(app.UIAxes3, 'Title');
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            app.UIAxes3.Position = [15 16 708 557];

            % Show the figure after all components are created
            app.ExternalUIFigure.Visible = 'on';
            
            %SpecNewWindow(app, app.UIAxes);
            app.OpenDiagraminaNewWindowButton.Enable = 'off';
            copyobj(app.UIAxes.Children,app.UIAxes3); 
            xlabel(app.UIAxes3,app.UIAxes.XLabel.String);
            ylabel(app.UIAxes3,app.UIAxes.YLabel.String);
            title(app.UIAxes3, app.UIAxes.Title.String);
            colormap(app.UIAxes3, app.UIAxes.Colormap);
            yticks(app.UIAxes3, app.UIAxes.YTick);
            yticklabels(app.UIAxes3, app.UIAxes.YTickLabel);
            set(app.UIAxes3, 'YDir', app.UIAxes.YDir);
            set(app.UIAxes3, 'xLim', app.UIAxes.XLim);
            set(app.UIAxes3, 'yLim', app.UIAxes.YLim);
        end

        % Button pushed function: OpenDiagraminaNewWindowButton_2
        function OpenDiagraminaNewWindowButton_2Pushed(app, event)
            delete(app.ExternalUIFigure);
            
            % Create SuperSpectrogramUIFigure and hide until all components are created
            app.ExternalUIFigure = uifigure('Visible', 'off');
            app.ExternalUIFigure.Position = [100 100 738 580];
            app.ExternalUIFigure.Name = 'Super Spectrogram';

            % Create UIAxes
            app.UIAxes3 = uiaxes(app.ExternalUIFigure);
            title(app.UIAxes3, 'Title');
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            app.UIAxes3.Position = [15 16 708 557];

            % Show the figure after all components are created
            app.ExternalUIFigure.Visible = 'on';
            
            %SpecNewWindow(app, app.UIAxes);
            copyobj(app.UIAxes2.Children,app.UIAxes3); 
            xlabel(app.UIAxes3,app.UIAxes2.XLabel.String);
            ylabel(app.UIAxes3,app.UIAxes2.YLabel.String);
            title(app.UIAxes3, app.UIAxes2.Title.String);
            colormap(app.UIAxes3, app.UIAxes2.Colormap);
            yticks(app.UIAxes3, app.UIAxes2.YTick);
            yticklabels(app.UIAxes3, app.UIAxes2.YTickLabel);
            set(app.UIAxes3, 'YDir', app.UIAxes2.YDir);
            set(app.UIAxes3, 'XLim', app.UIAxes2.XLim);
            set(app.UIAxes3, 'YLim', app.UIAxes2.YLim);
            app.OpenDiagraminaNewWindowButton_2.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SuperSpectrogramUIFigure = uifigure('Visible', 'off');
            app.SuperSpectrogramUIFigure.Name = 'SuperSpectrogram';
            app.TabGroup = uitabgroup(app.SuperSpectrogramUIFigure);
            app.DisplayfromFileTab = uitab(app.TabGroup);
            app.DisplayfromFileTab.Title = 'Display from File';
            app.UIAxes = uiaxes(app.DisplayfromFileTab);
            title(app.UIAxes, 'Short-Time Fourier Transform Representation of the Signal')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Notes')
            app.ComputeParametersButton = uibutton(app.DisplayfromFileTab, 'push');
            app.ComputeParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeParametersButtonPushed, true);
            app.ComputeParametersButton.Enable = 'off';
            app.ComputeParametersButton.Tooltip = {'Compute the Spectrogram and display the results'};
            app.ComputeParametersButton.Text = 'Compute Parameters';
            app.UpdateDiagramButton = uibutton(app.DisplayfromFileTab, 'push');
            app.UpdateDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDiagramButtonPushed, true);
            app.UpdateDiagramButton.Enable = 'off';
            app.UpdateDiagramButton.Tooltip = {'Update the diagram changing the Frequency Axis limits or the notes sequence'};
            app.UpdateDiagramButton.Text = 'Update Diagram';
            app.SelectFileButton = uibutton(app.DisplayfromFileTab, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Icon = 'Folder.png';
            app.SelectFileButton.Tooltip = {'Choose the audio file that you want to analyse'};
            app.SelectFileButton.Text = 'Select File';
            app.FileLocationLabel = uilabel(app.DisplayfromFileTab);
            app.FileLocationLabel.Visible = 'off';
            app.FileLocationLabel.Text = 'File Location: ';
            app.StatusEditFieldLabel = uilabel(app.DisplayfromFileTab);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.DisplayfromFileTab, 'text');
            app.StatusEditField.Editable = 'off';
            app.StatusEditField.Tooltip = {'Helps the user to process the signal.'};
            app.StatusEditField.Value = 'Press Select File';
            app.SpectreThresholddBEditField_2Label = uilabel(app.DisplayfromFileTab);
            app.SpectreThresholddBEditField_2Label.Text = 'Spectre Threshold (dB)';
            app.SpectreThresholddBEditField_2 = uieditfield(app.DisplayfromFileTab, 'numeric');
            app.SpectreThresholddBEditField_2.Limits = [-Inf 0];
            app.SpectreThresholddBEditField_2.ValueChangedFcn = createCallbackFcn(app, @SpectreThresholddBEditField_2ValueChanged, true);
            app.SpectreThresholddBEditField_2.Enable = 'off';
            app.SpectreThresholddBEditField_2.Tooltip = {'Define the analysis STFT threshold.'};
            app.SpectreThresholddBEditField_2.Value = -70;
            app.LowestNoteDropDown_2Label = uilabel(app.DisplayfromFileTab);
            app.LowestNoteDropDown_2Label.Text = 'Lowest Note';
            app.LowestNoteDropDown_2 = uidropdown(app.DisplayfromFileTab);
            app.LowestNoteDropDown_2.Items = {'sil', 'C0', 'C#0/Db0', 'D0', 'D#0/Eb0', 'E0', 'F0', 'F#0/Gb0', 'G0', 'G#0/Ab0', 'A0', 'A#0/Bb0', 'B0', 'C1', 'C#1/Db1', 'D1', 'D#1/Eb1', 'E1', 'F1', 'F#1/Gb1', 'G1', 'G#1/Ab1', 'A1', 'A#1/Bb1', 'B1', 'C2', 'C#2/Db2', 'D2', 'D#2/Eb2', 'E2', 'F2', 'F#2/Gb2', 'G2', 'G#2/Ab2', 'A2', 'A#2/Bb2', 'B2', 'C3', 'C#3/Db3', 'D3', 'D#3/Eb3', 'E3', 'F3', 'F#3/Gb3', 'G3', 'G#3/Ab3', 'A3', 'A#3/Bb3', 'B3', 'C4', 'C#4/Db4', 'D4', 'D#4/Eb4', 'E4', 'F4', 'F#4/Gb4', 'G4', 'G#4/Ab4', 'A4', 'A#4/Bb4', 'B4', 'C5', 'C#5/Db5', 'D5', 'D#5/Eb5', 'E5', 'F5', 'F#5/Gb5', 'G5', 'G#5/Ab5', 'A5', 'A#5/Bb5', 'B5', 'C6', 'C#6/Db6', 'D6', 'D#6/Eb6', 'E6', 'F6', 'F#6/Gb6', 'G6', 'G#6/Ab6', 'A6', 'A#6/Bb6', 'B6'};
            app.LowestNoteDropDown_2.ValueChangedFcn = createCallbackFcn(app, @LowestNoteDropDown_2ValueChanged, true);
            app.LowestNoteDropDown_2.Enable = 'off';
            app.LowestNoteDropDown_2.Tooltip = {'Define the lower frequency axis.'};
            app.LowestNoteDropDown_2.Value = 'sil';
            app.HighestNoteDropDown_2Label = uilabel(app.DisplayfromFileTab);
            app.HighestNoteDropDown_2Label.Text = 'Highest Note';
            app.HighestNoteDropDown_2 = uidropdown(app.DisplayfromFileTab);
            app.HighestNoteDropDown_2.Items = {'sil', 'C0', 'C#0/Db0', 'D0', 'D#0/Eb0', 'E0', 'F0', 'F#0/Gb0', 'G0', 'G#0/Ab0', 'A0', 'A#0/Bb0', 'B0', 'C1', 'C#1/Db1', 'D1', 'D#1/Eb1', 'E1', 'F1', 'F#1/Gb1', 'G1', 'G#1/Ab1', 'A1', 'A#1/Bb1', 'B1', 'C2', 'C#2/Db2', 'D2', 'D#2/Eb2', 'E2', 'F2', 'F#2/Gb2', 'G2', 'G#2/Ab2', 'A2', 'A#2/Bb2', 'B2', 'C3', 'C#3/Db3', 'D3', 'D#3/Eb3', 'E3', 'F3', 'F#3/Gb3', 'G3', 'G#3/Ab3', 'A3', 'A#3/Bb3', 'B3', 'C4', 'C#4/Db4', 'D4', 'D#4/Eb4', 'E4', 'F4', 'F#4/Gb4', 'G4', 'G#4/Ab4', 'A4', 'A#4/Bb4', 'B4', 'C5', 'C#5/Db5', 'D5', 'D#5/Eb5', 'E5', 'F5', 'F#5/Gb5', 'G5', 'G#5/Ab5', 'A5', 'A#5/Bb5', 'B5', 'C6', 'C#6/Db6', 'D6', 'D#6/Eb6', 'E6', 'F6', 'F#6/Gb6', 'G6', 'G#6/Ab6', 'A6', 'A#6/Bb6', 'B6', 'C7'};
            app.HighestNoteDropDown_2.ValueChangedFcn = createCallbackFcn(app, @HighestNoteDropDown_2ValueChanged, true);
            app.HighestNoteDropDown_2.Enable = 'off';
            app.HighestNoteDropDown_2.Tooltip = {'Define the higher frequency axis.'};
            app.HighestNoteDropDown_2.Value = 'C7';
            app.NotesSequenceDropDown_2Label = uilabel(app.DisplayfromFileTab);
            app.NotesSequenceDropDown_2Label.Text = 'Notes Sequence';
            app.NotesSequenceDropDown_2 = uidropdown(app.DisplayfromFileTab);
            app.NotesSequenceDropDown_2.Items = {'Higher up', 'Higher down'};
            app.NotesSequenceDropDown_2.ValueChangedFcn = createCallbackFcn(app, @NotesSequenceDropDown_2ValueChanged, true);
            app.NotesSequenceDropDown_2.Enable = 'off';
            app.NotesSequenceDropDown_2.Tooltip = {'It defines if the diagram shows the highest pitches above or below.'};
            app.NotesSequenceDropDown_2.Value = 'Higher down';
            app.PlaySelectedAudioButton = uibutton(app.DisplayfromFileTab, 'push');
            app.PlaySelectedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySelectedAudioButtonPushed, true);
            app.PlaySelectedAudioButton.Icon = 'Play.png';
            app.PlaySelectedAudioButton.Enable = 'off';
            app.PlaySelectedAudioButton.Tooltip = {'Play the audio portion defined by the sliders.'};
            app.PlaySelectedAudioButton.Text = 'Play Selected Audio';
            app.TargetRateHzDropDownLabel = uilabel(app.DisplayfromFileTab);
            app.TargetRateHzDropDownLabel.Text = 'Target Rate (Hz)';
            app.TargetRateHzDropDown = uidropdown(app.DisplayfromFileTab);
            app.TargetRateHzDropDown.Items = {'8820', '4410', '2205', '1102.5'};
            app.TargetRateHzDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetRateHzDropDownValueChanged, true);
            app.TargetRateHzDropDown.Enable = 'off';
            app.TargetRateHzDropDown.Tooltip = {'Sampling Frequency of the analyzed signal. Affects the highest note displayed.'};
            app.TargetRateHzDropDown.Value = '4410';
            app.UIAxes_2 = uiaxes(app.DisplayfromFileTab);
            title(app.UIAxes_2, 'Analyzed Audio Signal')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Amplitude')
            app.MinTimeSliderLabel = uilabel(app.DisplayfromFileTab);
            app.MinTimeSliderLabel.Text = 'MinTime';
            app.MinTimeSlider = uislider(app.DisplayfromFileTab);
            app.MinTimeSlider.ValueChangedFcn = createCallbackFcn(app, @MinTimeSliderValueChanged, true);
            app.MinTimeSlider.ValueChangingFcn = createCallbackFcn(app, @MinTimeSliderValueChanging, true);
            app.MinTimeSlider.Enable = 'off';
            app.MinTimeSlider.Tooltip = {'Configure the lower limit of the signal to be analyzed.'};
            app.MaxTimeSliderLabel = uilabel(app.DisplayfromFileTab);
            app.MaxTimeSliderLabel.Text = 'MaxTime';
            app.MaxTimeSlider = uislider(app.DisplayfromFileTab);
            app.MaxTimeSlider.ValueChangedFcn = createCallbackFcn(app, @MaxTimeSliderValueChanged, true);
            app.MaxTimeSlider.ValueChangingFcn = createCallbackFcn(app, @MaxTimeSliderValueChanging, true);
            app.MaxTimeSlider.Enable = 'off';
            app.MaxTimeSlider.Tooltip = {'Configure the higher limit of the signal to be analyzed.'};
            app.MaxTimeSlider.Value = 100;
            app.OpenDiagraminaNewWindowButton = uibutton(app.DisplayfromFileTab, 'push');
            app.OpenDiagraminaNewWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDiagraminaNewWindowButtonPushed, true);
            app.OpenDiagraminaNewWindowButton.Enable = 'off';
            app.OpenDiagraminaNewWindowButton.Tooltip = {'Display the Spectrogram in a separated window.'};
            app.OpenDiagraminaNewWindowButton.Text = 'Open Diagram in a New Window';
            app.RecordandStreamTab = uitab(app.TabGroup);
            app.RecordandStreamTab.Title = 'Record and Stream';
            app.UIAxes2 = uiaxes(app.RecordandStreamTab);
            title(app.UIAxes2, 'Short-Time Fourier Transform Representation of the Signal')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Notes')
            app.StartRecordingButton = uibutton(app.RecordandStreamTab, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Icon = 'Record.png';
            app.StartRecordingButton.Tooltip = {'Uses the main microphone defined by your computer to register the audio.'};
            app.StartRecordingButton.Text = 'Start Recording';
            app.SamplingRateHzDropDownLabel = uilabel(app.RecordandStreamTab);
            app.SamplingRateHzDropDownLabel.Text = 'Sampling Rate (Hz)';
            app.SamplingRateHzDropDown = uidropdown(app.RecordandStreamTab);
            app.SamplingRateHzDropDown.Items = {'8820', '4410', '2205', '1102.5'};
            app.SamplingRateHzDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingRateHzDropDownValueChanged, true);
            app.SamplingRateHzDropDown.Tooltip = {'Define the Sampling Rate of the recorded signal.'};
            app.SamplingRateHzDropDown.Value = '4410';
            app.RecordingTimesDropDownLabel = uilabel(app.RecordandStreamTab);
            app.RecordingTimesDropDownLabel.Text = 'Recording Time (s)';
            app.RecordingTimesDropDown = uidropdown(app.RecordandStreamTab);
            app.RecordingTimesDropDown.Items = {'5', '10', '15', '20', '30', '40', '60', '120'};
            app.RecordingTimesDropDown.ValueChangedFcn = createCallbackFcn(app, @RecordingTimesDropDownValueChanged, true);
            app.RecordingTimesDropDown.Tooltip = {'How many seconds of recording?'};
            app.RecordingTimesDropDown.Value = '30';
            app.LowestNoteDropDownLabel = uilabel(app.RecordandStreamTab);
            app.LowestNoteDropDownLabel.Text = 'Lowest Note';
            app.LowestNoteDropDown = uidropdown(app.RecordandStreamTab);
            app.LowestNoteDropDown.Items = {'sil', 'C0', 'C#0/Db0', 'D0', 'D#0/Eb0', 'E0', 'F0', 'F#0/Gb0', 'G0', 'G#0/Ab0', 'A0', 'A#0/Bb0', 'B0', 'C1', 'C#1/Db1', 'D1', 'D#1/Eb1', 'E1', 'F1', 'F#1/Gb1', 'G1', 'G#1/Ab1', 'A1', 'A#1/Bb1', 'B1', 'C2', 'C#2/Db2', 'D2', 'D#2/Eb2', 'E2', 'F2', 'F#2/Gb2', 'G2', 'G#2/Ab2', 'A2', 'A#2/Bb2', 'B2', 'C3', 'C#3/Db3', 'D3', 'D#3/Eb3', 'E3', 'F3', 'F#3/Gb3', 'G3', 'G#3/Ab3', 'A3', 'A#3/Bb3', 'B3', 'C4', 'C#4/Db4', 'D4', 'D#4/Eb4', 'E4', 'F4', 'F#4/Gb4', 'G4', 'G#4/Ab4', 'A4', 'A#4/Bb4', 'B4', 'C5', 'C#5/Db5', 'D5', 'D#5/Eb5', 'E5', 'F5', 'F#5/Gb5', 'G5', 'G#5/Ab5', 'A5', 'A#5/Bb5', 'B5', 'C6', 'C#6/Db6', 'D6', 'D#6/Eb6', 'E6', 'F6', 'F#6/Gb6', 'G6', 'G#6/Ab6', 'A6', 'A#6/Bb6', 'B6'};
            app.LowestNoteDropDown.ValueChangedFcn = createCallbackFcn(app, @LowestNoteDropDownValueChanged, true);
            app.LowestNoteDropDown.Tooltip = {'Define the lower frequency axis.'};
            app.LowestNoteDropDown.Value = 'sil';
            app.HighestNoteDropDownLabel = uilabel(app.RecordandStreamTab);
            app.HighestNoteDropDownLabel.Text = 'Highest Note';
            app.HighestNoteDropDown = uidropdown(app.RecordandStreamTab);
            app.HighestNoteDropDown.Items = {'sil', 'C0', 'C#0/Db0', 'D0', 'D#0/Eb0', 'E0', 'F0', 'F#0/Gb0', 'G0', 'G#0/Ab0', 'A0', 'A#0/Bb0', 'B0', 'C1', 'C#1/Db1', 'D1', 'D#1/Eb1', 'E1', 'F1', 'F#1/Gb1', 'G1', 'G#1/Ab1', 'A1', 'A#1/Bb1', 'B1', 'C2', 'C#2/Db2', 'D2', 'D#2/Eb2', 'E2', 'F2', 'F#2/Gb2', 'G2', 'G#2/Ab2', 'A2', 'A#2/Bb2', 'B2', 'C3', 'C#3/Db3', 'D3', 'D#3/Eb3', 'E3', 'F3', 'F#3/Gb3', 'G3', 'G#3/Ab3', 'A3', 'A#3/Bb3', 'B3', 'C4', 'C#4/Db4', 'D4', 'D#4/Eb4', 'E4', 'F4', 'F#4/Gb4', 'G4', 'G#4/Ab4', 'A4', 'A#4/Bb4', 'B4', 'C5', 'C#5/Db5', 'D5', 'D#5/Eb5', 'E5', 'F5', 'F#5/Gb5', 'G5', 'G#5/Ab5', 'A5', 'A#5/Bb5', 'B5', 'C6', 'C#6/Db6', 'D6', 'D#6/Eb6', 'E6', 'F6', 'F#6/Gb6', 'G6', 'G#6/Ab6', 'A6', 'A#6/Bb6', 'B6', 'C7'};
            app.HighestNoteDropDown.ValueChangedFcn = createCallbackFcn(app, @HighestNoteDropDownValueChanged, true);
            app.HighestNoteDropDown.Tooltip = {'Define the higher frequency axis.'};
            app.HighestNoteDropDown.Value = 'C7';
            app.RefreshRatesDropDownLabel = uilabel(app.RecordandStreamTab);
            app.RefreshRatesDropDownLabel.Text = 'Refresh Rate (s)';
            app.RefreshRatesDropDown = uidropdown(app.RecordandStreamTab);
            app.RefreshRatesDropDown.Items = {'5', '10', '15', '30'};
            app.RefreshRatesDropDown.Tooltip = {'Only related to the recording, smaller rates give a better time resolution during the recording.'};
            app.RefreshRatesDropDown.Value = '15';
            app.RecalculateSpectrumButton = uibutton(app.RecordandStreamTab, 'push');
            app.RecalculateSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculateSpectrumButtonPushed, true);
            app.RecalculateSpectrumButton.Enable = 'off';
            app.RecalculateSpectrumButton.Tooltip = {'Improves the quality of the Spectrogram, but only can process a small portion.'};
            app.RecalculateSpectrumButton.Text = 'Recalculate Spectrum';
            app.SpectreThresholddBEditFieldLabel = uilabel(app.RecordandStreamTab);
            app.SpectreThresholddBEditFieldLabel.Text = 'Spectre Threshold (dB)';
            app.SpectreThresholddBEditField = uieditfield(app.RecordandStreamTab, 'numeric');
            app.SpectreThresholddBEditField.Limits = [-Inf 0];
            app.SpectreThresholddBEditField.ValueChangedFcn = createCallbackFcn(app, @SpectreThresholddBEditFieldValueChanged, true);
            app.SpectreThresholddBEditField.Tooltip = {'Define the analysis STFT threshold.'};
            app.SpectreThresholddBEditField.Value = -70;
            app.NotesSequenceDropDownLabel = uilabel(app.RecordandStreamTab);
            app.NotesSequenceDropDownLabel.Text = 'Notes Sequence';
            app.NotesSequenceDropDown = uidropdown(app.RecordandStreamTab);
            app.NotesSequenceDropDown.Items = {'Higher up', 'Higher down'};
            app.NotesSequenceDropDown.ValueChangedFcn = createCallbackFcn(app, @NotesSequenceDropDownValueChanged, true);
            app.NotesSequenceDropDown.Tooltip = {'It defines if the diagram shows the highest pitches above or below.'};
            app.NotesSequenceDropDown.Value = 'Higher up';
            app.RefreshDiagramButton = uibutton(app.RecordandStreamTab, 'push');
            app.RefreshDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshDiagramButtonPushed, true);
            app.RefreshDiagramButton.Enable = 'off';
            app.RefreshDiagramButton.Tooltip = {'Updates the diagram according to the Notes to be displayed.'};
            app.RefreshDiagramButton.Text = 'Refresh Diagram';
            app.PlaySelectedAudioButton_2 = uibutton(app.RecordandStreamTab, 'push');
            app.PlaySelectedAudioButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlaySelectedAudioButton_2Pushed, true);
            app.PlaySelectedAudioButton_2.Icon = 'Play.png';
            app.PlaySelectedAudioButton_2.Enable = 'off';
            app.PlaySelectedAudioButton_2.Tooltip = {'Play the audio portion defined by the sliders.'};
            app.PlaySelectedAudioButton_2.Text = 'Play Selected Audio';
            app.StopRecordingButton = uibutton(app.RecordandStreamTab, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed2, true);
            app.StopRecordingButton.Enable = 'off';
            app.StopRecordingButton.Tooltip = {'Stop recording before the Recording Time ends'};
            app.StopRecordingButton.Text = 'Stop Recording';
            app.RecFreqDisplay = uilabel(app.RecordandStreamTab);
            app.RecFreqDisplay.Visible = 'off';
            app.RecFreqDisplay.Text = 'File Location: ';
            app.ExportRecordingasWavButton = uibutton(app.RecordandStreamTab, 'push');
            app.ExportRecordingasWavButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRecordingasWavButtonPushed, true);
            app.ExportRecordingasWavButton.Enable = 'off';
            app.ExportRecordingasWavButton.Tooltip = {'Save your recorded file as a .wav file for analysing later.'};
            app.ExportRecordingasWavButton.Text = 'Export Recording as .Wav';
            app.UIAxes_3 = uiaxes(app.RecordandStreamTab);
            title(app.UIAxes_3, 'Recorded Audio Signal')
            xlabel(app.UIAxes_3, 'Time (s)')
            ylabel(app.UIAxes_3, 'Amplitude')
            app.MinTimeSlider_2Label = uilabel(app.RecordandStreamTab);
            app.MinTimeSlider_2Label.Text = 'MinTime';
            app.MinTimeSlider_2 = uislider(app.RecordandStreamTab);
            app.MinTimeSlider_2.ValueChangedFcn = createCallbackFcn(app, @MinTimeSlider_2ValueChanged, true);
            app.MinTimeSlider_2.ValueChangingFcn = createCallbackFcn(app, @MinTimeSlider_2ValueChanging, true);
            app.MinTimeSlider_2.Enable = 'off';
            app.MinTimeSlider_2.Tooltip = {'Configure the lower limit of the signal to be analyzed.'};
            app.MaxTimeSlider_2Label = uilabel(app.RecordandStreamTab);
            app.MaxTimeSlider_2Label.Text = 'MaxTime';
            app.MaxTimeSlider_2 = uislider(app.RecordandStreamTab);
            app.MaxTimeSlider_2.ValueChangedFcn = createCallbackFcn(app, @MaxTimeSlider_2ValueChanged, true);
            app.MaxTimeSlider_2.ValueChangingFcn = createCallbackFcn(app, @MaxTimeSlider_2ValueChanging, true);
            app.MaxTimeSlider_2.Enable = 'off';
            app.MaxTimeSlider_2.Tooltip = {'Configure the higher limit of the signal to be analyzed.'};
            app.MaxTimeSlider_2.Value = 100;
            app.OpenDiagraminaNewWindowButton_2 = uibutton(app.RecordandStreamTab, 'push');
            app.OpenDiagraminaNewWindowButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenDiagraminaNewWindowButton_2Pushed, true);
            app.OpenDiagraminaNewWindowButton_2.Enable = 'off';
            app.OpenDiagraminaNewWindowButton_2.Tooltip = {'Display the Spectrogram in a separated window.'};
            app.OpenDiagraminaNewWindowButton_2.Text = 'Open Diagram in a New Window';
            app.ConfigsAboutTab = uitab(app.TabGroup);
            app.ConfigsAboutTab.Title = 'Configs/About';
            app.MicrophoneDropDownLabel = uilabel(app.ConfigsAboutTab);
            app.MicrophoneDropDownLabel.Text = 'Microphone';
            app.MicrophoneDropDown = uidropdown(app.ConfigsAboutTab);
            app.MicrophoneDropDown.Items = {'Default'};
            app.MicrophoneDropDown.Value = 'Default';
            app.SpeakerDropDownLabel = uilabel(app.ConfigsAboutTab);
            app.SpeakerDropDownLabel.Text = 'Speaker';
            app.SpeakerDropDown = uidropdown(app.ConfigsAboutTab);
            app.SpeakerDropDown.Items = {'Default'};
            app.SpeakerDropDown.Value = 'Default';
            app.AboutTextAreaLabel = uilabel(app.ConfigsAboutTab);
            app.AboutTextAreaLabel.Text = 'About';
            app.AboutTextArea = uitextarea(app.ConfigsAboutTab);
            app.AboutTextArea.Editable = 'off';
            app.AboutTextArea.Value = {'SuperSpectrogram v0.5.3'; '(19 May 2022)'; ''; 'Developed by Guilherme Pavelski'; 'STFT Based on Victor Chen''s implementation'; 'For comments, suggestions, bug reporting, and other questions, please visit:'; 'https://github.com/gpavelski/SuperSpectrogram'; ''; 'Program created using MATLAB r2020a '; ''; ''};
            app.LicenseTextAreaLabel = uilabel(app.ConfigsAboutTab);
            app.LicenseTextAreaLabel.Text = 'License';
            app.LicenseTextArea = uitextarea(app.ConfigsAboutTab);
            app.LicenseTextArea.Editable = 'off';
            app.LicenseTextArea.Value = {'MIT License'; ''; 'Copyright (c) 2022 Guilherme Pavelski'; ''; 'Permission is hereby granted, free of charge, to any person obtaining a copy'; 'of this software and associated documentation files (the "Software"), to deal'; 'in the Software without restriction, including without limitation the rights'; 'to use, copy, modify, merge, publish, distribute, sublicense, and/or sell'; 'copies of the Software, and to permit persons to whom the Software is'; 'furnished to do so, subject to the following conditions:'; ''; 'The above copyright notice and this permission notice shall be included in all'; 'copies or substantial portions of the Software.'; ''; 'THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND, EXPRESS OR'; 'IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF MERCHANTABILITY,'; 'FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT. IN NO EVENT SHALL THE'; 'AUTHORS OR COPYRIGHT HOLDERS BE LIABLE FOR ANY CLAIM, DAMAGES OR OTHER'; 'LIABILITY, WHETHER IN AN ACTION OF CONTRACT, TORT OR OTHERWISE, ARISING FROM,'; 'OUT OF OR IN CONNECTION WITH THE SOFTWARE OR THE USE OR OTHER DEALINGS IN THE'; 'SOFTWARE.'};
            % Show the figure after all components are created
            app.SuperSpectrogramUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SuperSpectrogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SuperSpectrogramUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SuperSpectrogramUIFigure)
        end
    end
end